classdef T1mapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        T1mappUIFigure                matlab.ui.Figure
        FitResultsTabGroup            matlab.ui.container.TabGroup
        FitResultsTab                 matlab.ui.container.Tab
        FittedT1Label                 matlab.ui.control.Label
        DESPOT1Label                  matlab.ui.control.Label
        SignalflipangleLabel          matlab.ui.control.Label
        FitYLabel1                    matlab.ui.control.Label
        SignalFunctionImage           matlab.ui.control.Image
        ColormapButtonGroup           matlab.ui.container.ButtonGroup
        AutoButton                    matlab.ui.control.Button
        MaxmsLabel                    matlab.ui.control.Label
        MaxT1EditField                matlab.ui.control.NumericEditField
        hotButton                     matlab.ui.control.RadioButton
        parulaButton                  matlab.ui.control.RadioButton
        winterButton                  matlab.ui.control.RadioButton
        autumnButton                  matlab.ui.control.RadioButton
        grayButton                    matlab.ui.control.RadioButton
        FitImageSelectorPanel         matlab.ui.container.Panel
        FitFrameLabel                 matlab.ui.control.Label
        FitFrameSlider                matlab.ui.control.Slider
        FitFrameEditField             matlab.ui.control.NumericEditField
        FitSliceLabel                 matlab.ui.control.Label
        FitOrientationSpinner         matlab.ui.control.Spinner
        OrientationSpinner_2Label     matlab.ui.control.Label
        FitSliceSlider                matlab.ui.control.Slider
        FitSliceEditField             matlab.ui.control.NumericEditField
        T1Label                       matlab.ui.control.Label
        M0Label                       matlab.ui.control.Label
        FitXLabel1                    matlab.ui.control.Label
        FitXLabel2                    matlab.ui.control.Label
        FitYLabel2                    matlab.ui.control.Label
        Image1                        matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
        ROIbutton                     matlab.ui.control.Button
        EraseButton                   matlab.ui.control.Button
        T1Image                       matlab.ui.control.UIAxes
        M0Image                       matlab.ui.control.UIAxes
        DespotT1FitFig                matlab.ui.control.UIAxes
        T1FitFig                      matlab.ui.control.UIAxes
        RawImagesTabGroup             matlab.ui.container.TabGroup
        RawImagesTab                  matlab.ui.container.Tab
        Image3                        matlab.ui.control.Image
        YLabel                        matlab.ui.control.Label
        XLabel                        matlab.ui.control.Label
        ImageLabel                    matlab.ui.control.Label
        IntensityLabel                matlab.ui.control.Label
        IntensitySlider               matlab.ui.control.Slider
        SegmentationPanel             matlab.ui.container.Panel
        OpenButton                    matlab.ui.control.Button
        CloseButton                   matlab.ui.control.Button
        DilateButton                  matlab.ui.control.Button
        ErodeButton                   matlab.ui.control.Button
        ThresholdSlider               matlab.ui.control.Slider
        ThresholdEditField            matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel       matlab.ui.control.Label
        ImageSelectorPanel            matlab.ui.container.Panel
        FrameEditField                matlab.ui.control.NumericEditField
        frameEditFieldLabel           matlab.ui.control.Label
        FrameSlider                   matlab.ui.control.Slider
        SliceLabel                    matlab.ui.control.Label
        OrientationSpinner            matlab.ui.control.Spinner
        OrientationSpinnerLabel       matlab.ui.control.Label
        FlipAngleSlider               matlab.ui.control.Slider
        FlipAngleEditField            matlab.ui.control.NumericEditField
        flipangleEditFieldLabel       matlab.ui.control.Label
        SliceSlider                   matlab.ui.control.Slider
        SliceEditField                matlab.ui.control.NumericEditField
        RawImage                      matlab.ui.control.UIAxes
        B1Tab                         matlab.ui.container.Tab
        WORKINPROGRESSLabel           matlab.ui.control.Label
        B1XLabel                      matlab.ui.control.Label
        B1YLabel                      matlab.ui.control.Label
        B1OrientationSpinner          matlab.ui.control.Spinner
        B1OrientationSpinnerLabel     matlab.ui.control.Label
        B1MapCheckBox                 matlab.ui.control.CheckBox
        B1LoadMRDButton               matlab.ui.control.Button
        B1SliceLabel                  matlab.ui.control.Label
        B1SliceSlider                 matlab.ui.control.Slider
        B1SliceEditField              matlab.ui.control.NumericEditField
        SequenceParametersTabGroup    matlab.ui.container.TabGroup
        SequenceParametersTab         matlab.ui.container.Tab
        FlipAnglesViewField           matlab.ui.control.EditField
        FlipanglesEditFieldLabel      matlab.ui.control.Label
        FOVViewField1                 matlab.ui.control.NumericEditField
        FOVmmLabel                    matlab.ui.control.Label
        MatrixViewField1              matlab.ui.control.NumericEditField
        MatrixEditFieldLabel          matlab.ui.control.Label
        SequenceViewField             matlab.ui.control.EditField
        SequenceEditFieldLabel        matlab.ui.control.Label
        TEViewField                   matlab.ui.control.NumericEditField
        TEmsLabel                     matlab.ui.control.Label
        TRViewField                   matlab.ui.control.NumericEditField
        TRmsLabel                     matlab.ui.control.Label
        ZLabelFOV                     matlab.ui.control.Label
        YLabelFOV                     matlab.ui.control.Label
        XLabelFOV                     matlab.ui.control.Label
        FOVViewField3                 matlab.ui.control.NumericEditField
        FOVViewField2                 matlab.ui.control.NumericEditField
        MatrixViewField3              matlab.ui.control.NumericEditField
        MatrixViewField2              matlab.ui.control.NumericEditField
        MessagesTabGroup              matlab.ui.container.TabGroup
        MessagesTab                   matlab.ui.container.Tab
        statusLabel                   matlab.ui.control.Label
        StatusImage                   matlab.ui.control.Image
        BackgroundBox                 matlab.ui.control.TextArea
        MessageBox                    matlab.ui.control.TextArea
        DashboardTabGroup             matlab.ui.container.TabGroup
        DashboardTab                  matlab.ui.container.Tab
        minsecLabel                   matlab.ui.control.Label
        EstimatedFitTimeViewField     matlab.ui.control.EditField
        EstimatedtimeremainingEditFieldLabel  matlab.ui.control.Label
        FitRangeFramesStartEditField  matlab.ui.control.NumericEditField
        FramesLabel                   matlab.ui.control.Label
        FitRangeFramesEndEditField    matlab.ui.control.NumericEditField
        FitRangeSlicesStartEditField  matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        FitRangeSlicesEndEditField    matlab.ui.control.NumericEditField
        Lamp7                         matlab.ui.control.Lamp
        Lamp6                         matlab.ui.control.Lamp
        Lamp5                         matlab.ui.control.Lamp
        Lamp4                         matlab.ui.control.Lamp
        Lamp3                         matlab.ui.control.Lamp
        Lamp2                         matlab.ui.control.Lamp
        Lamp1                         matlab.ui.control.Lamp
        AbortButton                   matlab.ui.control.Button
        FitProgressGauge              matlab.ui.control.LinearGauge
        ExitButton                    matlab.ui.control.Button
        ExportGIFButton               matlab.ui.control.Button
        ExportNIFTIButton             matlab.ui.control.Button
        ExportDCMButton               matlab.ui.control.Button
        FitButton                     matlab.ui.control.Button
        RegistrationProgressGauge     matlab.ui.control.LinearGauge
        SegmentButton                 matlab.ui.control.Button
        RegisterButton                matlab.ui.control.Button
        LoadMRDButton                 matlab.ui.control.Button
        MRSImage                      matlab.ui.control.Image
        TulpImage                     matlab.ui.control.Image
        CreditsLabel                  matlab.ui.control.Label
        TitleLabel                    matlab.ui.control.Label
    end


    %% ----------------------------------------------------------------------------
    %
    %   3D T1 mapping
    %
    %   Gustav Strijkers
    %   g.j.strijkers@amsterdamumc.nl
    %   Amsterdam UMC
    %
    %   Sept 2023
    %
    %
    %   Version 1.0
    %   - initial version
    %
    %   Version 2.0
    %   - app overhaul
    %   - added mulitple echo times, for cardiac T1 mapping
    %
    %   Version 2.1
    %   - cross-hair and ROI T1 value display
    %   - export bug fixes
    %
    %   Version 2.2
    %   - B1 map correction
    %
    %   Version 2.3
    %   - Improved ROI editing
    %
    %   Version 3.0
    %   - General update and maintenance
    %   - Dicom export bug fix
    %
    %
    %
    %
    %% ----------------------------------------------------------------------------


    properties (Access = public)

        % App version
        appVersion = "3.1.3"        % App version
        bartVersion                 % Bart version
        
        % file parameters
        validDataFileFlag           % valid data file true or false
        validB1Flag                 % valid B1 correction data file true or false
        validRecoFlag               % valid reconstruction true or false
        validFitFlag                % valid fit data true or false
        validMaskFlag               % valid mask true or false
        bartDetectedFlag            % bart toolbox detected true or false
        dataImportPath              % data import path
        dicomExportPath             % dicom export path
        niftiExportPath             % nifti export path
        gifExportPath               % gif export path
        appDir                      % application directory
        oldDir                      % starting directory
        mrdFlag                     % search for system MRD directory true or false
        
        % sequence parameters
        mrdParameters               % mrd file parameters
        parameters                  % sequence parameters
        B1Parameters                % B1 sequence parameters
        B1FlipAngles                % B1 flip angle list
        
        % images
        image5D                     % image data (flipangles, echoes, x , y , z);
        imageT1                     % 3D T1 map (x, y, z)
        imageM0                     % 3D M0 map (x, y, z)
        image4D                     % B1 image data (flipangles, x, y, z);
        imageB1                     % 3D B1 map (x, y, z)
        
        % plot parameters
        t1Cmap                      % T1 color map
        flipAngles                  % flip angles
        imageOrient                 % image orientations
        B1Orient                    % B1 image orientations
        imageOrientLabel            % image orientation text labels
        maskOrient                  % mask orientation
        maskOrientR                 % reverse mask orienation
        threshold                   % image threshold for segmentation
        mask                        % image mask for segmentation
        maxIndx                     % number of the flip angle image with highest intensity, used for segmentation
        cross                       % cross-hair
        roiFlag                     % roi drawing true or false
        roiPoints                   % roi point coordinates
        roi                         % roi drawing object

        % Colors
        green                       % other shade of green
        brightgreen                 % green
        red                         % other shade of red
        brightred                   % red
        blue                        % blue
        yellow                      % yellow
        orange                      % orange
        black                       % black

        % Sounds
        warningSound                % warning sound
        errorSound                  % error sound
        
        % fitting
        abortFitFlag                % abort fit true or false
        
    end
    
   
    methods (Access = private)


        % -------------------------------------------------
        % Buttons and lights controls
        % -------------------------------------------------
        
        
        function SetLights(app,code)
            
            if ~strcmp(code{1},'Null') app.Lamp1.Color = code{1}; end %#ok<*SEPEX> 
            if ~strcmp(code{2},'Null') app.Lamp2.Color = code{2}; end
            if ~strcmp(code{3},'Null') app.Lamp3.Color = code{3}; end
            if ~strcmp(code{4},'Null') app.Lamp4.Color = code{4}; end
            if ~strcmp(code{5},'Null') app.Lamp5.Color = code{5}; end
            if ~strcmp(code{6},'Null') app.Lamp6.Color = code{6}; end
            if ~strcmp(code{7},'Null') app.Lamp7.Color = code{7}; end
            drawnow;
            
        end
        
        
        function result = GetLights(app)
            
            result{1} = app.Lamp1.Color;
            result{2} = app.Lamp2.Color;
            result{3} = app.Lamp3.Color;
            result{4} = app.Lamp4.Color;
            result{5} = app.Lamp5.Color;
            result{6} = app.Lamp6.Color;
            result{7} = app.Lamp7.Color;
            
        end
        
        
        function SetButtons(app,code)
            
            if ~strcmp(code{1},'Null') app.LoadMRDButton.Enable = code{1}; end
            if ~strcmp(code{2},'Null') app.RegisterButton.Enable = code{2}; end
            if ~strcmp(code{3},'Null') app.SegmentButton.Enable = code{3}; end
            if ~strcmp(code{4},'Null') app.FitButton.Enable = code{4}; end
            if ~strcmp(code{5},'Null') app.ExportDCMButton.Enable = code{5}; end
            if ~strcmp(code{6},'Null') app.ExportNIFTIButton.Enable = code{6}; end
            if ~strcmp(code{7},'Null') app.ExportGIFButton.Enable = code{7}; end
            if ~strcmp(code{8},'Null') app.ExitButton.Enable = code{8}; end
            drawnow;
            
        end
        
        
        function result = GetButtons(app)
            
            result{1} = app.LoadMRDButton.Enable;
            result{2} = app.RegisterButton.Enable;
            result{3} = app.SegmentButton.Enable;
            result{4} = app.FitButton.Enable;
            result{5} = app.ExportDCMButton.Enable;
            result{6} = app.ExportNIFTIButton.Enable;
            result{7} = app.ExportGIFButton.Enable;
            result{8} = app.ExitButton.Enable;
            
        end
        
        
        function DisableSegmentButtons(app)
            
            app.SegmentButton.Enable = 'off';
            app.ThresholdEditField.Enable = 'off';
            app.ThresholdSlider.Enable = 'off';
            app.ErodeButton.Enable = 'off';
            app.DilateButton.Enable = 'off';
            app.CloseButton.Enable = 'off';
            app.OpenButton.Enable = 'off';
            
        end
        
        
        function EnableSegmentButtons(app)
            
            app.SegmentButton.Enable = 'on';
            app.ThresholdEditField.Enable = 'on';
            app.ThresholdSlider.Enable = 'on';
            app.ErodeButton.Enable = 'on';
            app.DilateButton.Enable = 'on';
            app.CloseButton.Enable = 'on';
            app.OpenButton.Enable = 'on';
            
        end
        
        
        % Prepare the app layout
        function SetAppLayoutProperties(app)
        
            % Version
            app.CreditsLabel.Text = strcat("Gustav Strijkers, Amsterdam UMC, Version ",app.appVersion);

            % Logos
            app.TulpImage.ImageSource = 'AmsterdamUMC.gif';
            app.MRSImage.ImageSource = 'MRS.gif';
            app.Image1.ImageSource = 'square.gif';
            app.Image2.ImageSource = 'square.gif';
            app.Image3.ImageSource = 'square.gif';
            app.SignalFunctionImage.ImageSource = 'S.gif';
            
            app.LoadMRDButton.Icon = 'disk.png';
            app.RegisterButton.Icon = 'register.png';
            app.SegmentButton.Icon = 'segmentation.png';
            app.FitButton.Icon = 'fitting.png';
            app.ExportDCMButton.Icon = 'disk.png';
            app.ExportNIFTIButton.Icon = 'disk.png';
            app.ExportGIFButton.Icon = 'disk.png';
            app.ExitButton.Icon = 'exit.png';
        
            % Load sounds
            [app.warningSound,~] = audioread('Glass.aiff');
            [app.errorSound,~] = audioread('Ping.aiff');

            % Define app colors
            app.brightred = 'Red';
            app.red = '#D95319';
            app.brightgreen = 'Green'; 
            app.green = '#6CB557';
            app.yellow = 'Yellow';
            app.orange = '#FDA400';
            app.blue = '#0073BE';
            app.black = 'Black';
            
            % Set some plot properties
            app.RawImage.Toolbar.Visible = 'off';
            app.T1FitFig.Toolbar.Visible = 'off';
            app.DespotT1FitFig.Toolbar.Visible = 'off';
            app.T1Image.Toolbar.Visible = 'off';
            app.M0Image.Toolbar.Visible = 'off';
            
            % Switch off plot interactivity, except for movie plot
            app.RawImage.Interactions = [];
            app.T1FitFig.Interactions = [];
            app.DespotT1FitFig.Interactions = [];
            app.T1Image.Interactions = [];
            app.M0Image.Interactions = [];
            
        end


        % ------------------------------------------
        % Image acquisition data parameters
        % ------------------------------------------
        
        
        function SetParametersFcn(app)
            
            % Sequence parameters
            app.parameters.matrix = [size(app.image5D,3),size(app.image5D,4),size(app.image5D,5)];
            aratio = app.parameters.FOVf/8;
            app.parameters.FOV3D = [app.parameters.FOV,aratio*app.parameters.FOV,app.parameters.SLICE_THICKNESS];
            app.parameters.NO_SLICES = app.parameters.matrix(3);
            app.parameters.SLICE_THICKNESS = app.parameters.FOV3D(3)/app.parameters.matrix(3);
            app.parameters.SLICE_INTERLEAVE = 1;
            app.parameters.SLICE_SEPARATION = app.parameters.SLICE_THICKNESS;
            app.parameters.SMX = app.parameters.FOV3D(1)/app.parameters.matrix(1);
            app.parameters.SMY = app.parameters.FOV3D(2)/app.parameters.matrix(2);
            app.parameters.FOV = app.parameters.FOV3D(1);
            app.parameters.NO_VIEWS = app.parameters.matrix(2);
            app.parameters.NO_VIEWS_2 = app.parameters.matrix(3);
            app.parameters.alpha = app.flipAngles(1);
            app.parameters.frametime = app.parameters.ti;
            
            % Show the sequence parameters in the app
            app.SequenceViewField.Value = app.parameters.PPL;
            if isfield(app.parameters,'tr_extra_us')
               app.parameters.tr = floor(app.parameters.tr) + app.parameters.tr_extra_us/1000;
            end
            app.TRViewField.Value = app.parameters.tr;
            app.TEViewField.Value = app.parameters.te;
            app.MatrixViewField1.Value = app.parameters.matrix(1);
            app.MatrixViewField2.Value = app.parameters.matrix(2);
            app.MatrixViewField3.Value = app.parameters.matrix(3);
            app.FOVViewField1.Value = app.parameters.FOV3D(1);
            app.FOVViewField2.Value = app.parameters.FOV3D(2);
            app.FOVViewField3.Value = app.parameters.FOV3D(3);
            app.FlipAnglesViewField.Value = num2str(app.flipAngles);
            
            % Image orientation labels
            app.imageOrient = [1 2 3 4 5; 1 2 4 5 3 ; 1 2 5 3 4 ];
            app.imageOrientLabel = ['X' 'Y' 'Z' ; 'Y' 'Z' 'X' ; 'Z' 'X' 'Y'];
            app.maskOrient = [1 2 3 4; 1 3 4 2 ; 1 4 2 3 ];
            app.B1Orient = [1 2 3; 2 3 1 ; 3 1 2];
            if app.parameters.PHASE_ORIENTATION
                app.XLabelFOV.Text = 'Y';
                app.YLabelFOV.Text = 'X';
            else
                app.XLabelFOV.Text = 'X';
                app.YLabelFOV.Text = 'Y';
            end
            
            % Limits to some values
            app.FlipAngleEditField.Value = 1;
            app.FlipAngleEditField.Limits = [1 length(app.flipAngles)];
            app.FlipAngleSlider.Value = 1;
            app.FlipAngleSlider.Limits = [1 length(app.flipAngles)];
            if app.parameters.NO_ECHOES > 1
                app.FrameEditField.Value = 1;
                app.FrameEditField.Limits = [1 app.parameters.NO_ECHOES];
                app.FrameSlider.Value = 1;
                app.FrameSlider.Limits = [1 app.parameters.NO_ECHOES];
                app.FrameEditField.Enable = 'on';
                app.FrameSlider.Enable = 'on';
                app.FitFrameEditField.Value = 1;
                app.FitFrameEditField.Limits = [1 app.parameters.NO_ECHOES];
                app.FitFrameSlider.Value = 1;
                app.FitFrameSlider.Limits = [1 app.parameters.NO_ECHOES];
                app.FitFrameEditField.Enable = 'on';
                app.FitFrameSlider.Enable = 'on';
                app.FitRangeFramesStartEditField.Value = 1;
                app.FitRangeFramesStartEditField.Limits = [1 app.parameters.NO_ECHOES];
                app.FitRangeFramesEndEditField.Value = 1;
                app.FitRangeFramesEndEditField.Limits = [1 app.parameters.NO_ECHOES];
                app.FitRangeFramesEndEditField.Value = app.parameters.NO_ECHOES;
                app.FitRangeFramesStartEditField.Enable = 'on';
                app.FitRangeFramesEndEditField.Enable = 'on';
            else
                app.FrameEditField.Enable = 'off';
                app.FrameSlider.Enable = 'off';
                app.FrameEditField.Value = 1;
                app.FrameEditField.Limits = [1 2];
                app.FrameSlider.Value = 1;
                app.FrameSlider.Limits = [1 2];
                app.FitFrameEditField.Enable = 'off';
                app.FitFrameSlider.Enable = 'off';
                app.FitFrameEditField.Value = 1;
                app.FitFrameEditField.Limits = [1 2];
                app.FitFrameSlider.Value = 1;
                app.FitFrameSlider.Limits = [1 2];
                app.FitRangeFramesStartEditField.Enable = 'off';
                app.FitRangeFramesEndEditField.Enable = 'off';
                app.FitRangeFramesStartEditField.Value = 1;
                app.FitRangeFramesStartEditField.Limits = [1 2];
                app.FitRangeFramesEndEditField.Value = 1;
                app.FitRangeFramesEndEditField.Limits = [1 2];
            end
            app.ThresholdEditField.Value = 1;
            app.ThresholdEditField.Limits = [1 32767];
            app.ThresholdSlider.Value = 1;
            app.ThresholdSlider.Limits = [1 32767];
            app.FitRangeSlicesStartEditField.Value = 1;
            app.FitRangeSlicesStartEditField.Limits = [1 app.parameters.matrix(3)];
            app.FitRangeSlicesEndEditField.Value = 1;
            app.FitRangeSlicesEndEditField.Limits = [1 app.parameters.matrix(3)];
            app.FitRangeSlicesEndEditField.Value = app.parameters.matrix(3);
            
            % Determine which flip angle has the highest intensity
            for i = 1:length(app.flipAngles)
                for j = 1:app.parameters.NO_ECHOES
                    avgs(i) = mean(app.image5D(i,j,:));  
                end
            end
            [~,app.maxIndx] = max(avgs);
            app.FlipAngleEditField.Value = app.maxIndx;
            app.FlipAngleSlider.Value = app.maxIndx;
            
            % Initialize fitting mask
            app.mask = ones([size(app.image5D,2),size(app.image5D,3),size(app.image5D,4),size(app.image5D,5)]);
            
            % Initialize the T1, M0, and B1 maps
            app.imageT1 = zeros([size(app.image5D,2),size(app.image5D,3),size(app.image5D,4),size(app.image5D,5)]);
            app.imageM0 = zeros([size(app.image5D,2),size(app.image5D,3),size(app.image5D,4),size(app.image5D,5)]);
            app.imageB1 = ones([size(app.image5D,2),size(app.image5D,3),size(app.image5D,4),size(app.image5D,5)]);
            app.IntensitySlider.Value = 1;
            app.t1Cmap = parula;
            app.t1Cmap(1,:) = [0 0 0];
            app.parulaButton.Value = 1;
            app.roiFlag = false;
            app.roiPoints = [];
            delete(app.roi);
            cla(app.RawImage);
            cla(app.T1Image);
            cla(app.M0Image);
            cla(app.T1FitFig);
            cla(app.DespotT1FitFig);
            
            
        end % SetParametersFcn


        % ------------------------------------------
        % Plotting figures and images
        % ------------------------------------------


        function result = CalcAspectratioFnc(app,dim1label,dim2label,sizex,sizey)
        
            if strcmp(dim1label,'X') L1 = app.parameters.FOV3D(1); end
            if strcmp(dim1label,'Y') L1 = app.parameters.FOV3D(2); end    
            if strcmp(dim1label,'Z') L1 = app.parameters.FOV3D(3); end
            if strcmp(dim2label,'X') L2 = app.parameters.FOV3D(1); end
            if strcmp(dim2label,'Y') L2 = app.parameters.FOV3D(2); end    
            if strcmp(dim2label,'Z') L2 = app.parameters.FOV3D(3); end 
             
            result = (L2/L1)*(sizex/sizey);
            
        end % Aspectratio


        function PlotImageFig(app)
            
            cla(app.RawImage);
            hold(app.RawImage,'on');
            
            if app.validRecoFlag
                
                % Define the colormap
                % Since it is not possible to have 2 colormaps in 1 figure, the
                % colormap is split in a grayscale part [0 1] and a red-color overlay part [1 2]
                cmap1 = [linspace(0,1,128)',linspace(0,1,128)',linspace(0,1,128)'];
                cmap2 = [linspace(0,1,128)', zeros(128,2)];
                cmap = [cmap1;cmap2];
                cmap(cmap > 1) = 1;
                
                % Select the data
                im = app.image5D;
                
                % Estimate a suitable scale
                scale = 1 + 32764 * (2-app.IntensitySlider.Value); 
                
                % Select which slice to show
                im = permute(im,app.imageOrient(app.OrientationSpinner.Value,:));
                im = squeeze(im(app.FlipAngleEditField.Value,app.FrameEditField.Value,:,:,app.SliceEditField.Value));
                
                % Label the orientations
                app.SliceLabel.Text = app.imageOrientLabel(app.OrientationSpinner.Value,3);
                app.XLabel.Text = app.imageOrientLabel(app.OrientationSpinner.Value,1);
                app.YLabel.Text = app.imageOrientLabel(app.OrientationSpinner.Value,2);
                                
                % Image aspect ratio
                if strcmp(app.XLabel.Text,'X') && strcmp(app.YLabel.Text,'Y') && app.parameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                    aratio = CalcAspectratioFnc(app,app.YLabel.Text,app.XLabel.Text,size(im,1),size(im,2));
                else
                    aratio = CalcAspectratioFnc(app,app.XLabel.Text,app.YLabel.Text,size(im,1),size(im,2));
                end
                
                % Background image
                app.RawImage.Position = [21,55,300,300];
                xlim(app.RawImage,[0, size(im,1) + 1]);
                ylim(app.RawImage,[0, size(im,2) + 1]);
                im = im/scale; im(im>1)=0.9999;
                backgroundimage = imshow(rot90(im),[0 2],'Colormap',cmap,'InitialMagnification','fit','Parent',app.RawImage); %#ok<NASGU> 
                
                % Overlay image
                if app.validMaskFlag
                    overlay = permute(app.mask,app.maskOrient(app.OrientationSpinner.Value,:));
                    overlay = squeeze(overlay(app.FrameEditField.Value,:,:,app.SliceEditField.Value));
                    if strcmp(app.XLabel.Text,'X') && strcmp(app.YLabel.Text,'Y') && app.parameters.PHASE_ORIENTATION
                        overlay = rot90(overlay,-1);
                    end
                    overlayimage = imshow(rot90(2 - overlay),[0 2],'Colormap',cmap,'InitialMagnification','fit','Parent',app.RawImage);
                    alpha = 0.7-0.7*rot90(overlay);
                    set(overlayimage,'AlphaData',alpha);
                end
                
                % Set aspect ratio
                daspect(app.RawImage,[aratio 1 1]);
                
            end
             
            hold(app.RawImage,'off');
            drawnow;
            
        end % PlotImageFig


        function PlotB1Fig(app)
            
            cla(app.B1Image);
            hold(app.B1Image,'on');
            
            if app.validRecoFlag
                
                
                % Select the data
                im = app.imageB1;
                
                % Select which slice to show
                im = permute(im,app.B1Orient(app.B1OrientationSpinner.Value,:));
                im = squeeze(im(:,:,app.B1SliceEditField.Value));
                B1mask = permute(app.mask,app.maskOrient(app.B1OrientationSpinner.Value,:));
                B1mask = squeeze(B1mask(1,:,:,app.B1SliceEditField.Value));
                im = im.*B1mask;
            
                % Label the orientations
                app.B1SliceLabel.Text = app.imageOrientLabel(app.B1OrientationSpinner.Value,3);
                app.B1XLabel.Text = app.imageOrientLabel(app.B1OrientationSpinner.Value,1);
                app.B1YLabel.Text = app.imageOrientLabel(app.B1OrientationSpinner.Value,2);
                                
                % Image aspect ratio
                if strcmp(app.B1XLabel.Text,'X') && strcmp(app.B1YLabel.Text,'Y') && app.B1Parameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                    aratio = CalcAspectratioFnc(app,app.B1YLabel.Text,app.B1XLabel.Text,size(im,1),size(im,2));
                else
                    aratio = CalcAspectratioFnc(app,app.B1XLabel.Text,app.B1YLabel.Text,size(im,1),size(im,2));
                end
                
                % Plot the image
                app.RawImage.Position = [21,55,300,300];
                xlim(app.B1Image,[0, size(im,1) + 1]);
                ylim(app.B1Image,[0, size(im,2) + 1]);
                imshow(rot90(im),[0.5 1.5],'InitialMagnification','fit','Parent',app.B1Image);
                
                % Set aspect ratio
                daspect(app.B1Image,[aratio 1 1]);
                
            end
             
            hold(app.RawImage,'off');
            drawnow;
            
        end % PlotB1Fig


        function PlotMapsFig(app)
            
            cla(app.T1Image);
            cla(app.M0Image);
            hold(app.T1Image,'on');
            hold(app.M0Image,'on');
            
            if app.validFitFlag
                
                % ----- T1 map -----
                
                % Select the data for the T1 map
                im = app.imageT1;
                
                % Select which slice to show
                im = permute(im,app.maskOrient(app.FitOrientationSpinner.Value,:));
                im = squeeze(im(app.FitFrameEditField.Value,:,:,app.FitSliceEditField.Value));
                
                % Label the orientations
                app.FitSliceLabel.Text = app.imageOrientLabel(app.FitOrientationSpinner.Value,3);
                app.FitXLabel1.Text = app.imageOrientLabel(app.FitOrientationSpinner.Value,1);
                app.FitYLabel1.Text = app.imageOrientLabel(app.FitOrientationSpinner.Value,2);
                app.FitXLabel2.Text = app.imageOrientLabel(app.FitOrientationSpinner.Value,1);
                app.FitYLabel2.Text = app.imageOrientLabel(app.FitOrientationSpinner.Value,2);
                
                % Image aspect ratio
                if strcmp(app.FitXLabel1.Text,'X') && strcmp(app.FitYLabel1.Text,'Y') && app.parameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                    aratio = CalcAspectratioFnc(app,app.FitYLabel1.Text,app.FitXLabel1.Text,size(im,1),size(im,2));
                else
                    aratio = CalcAspectratioFnc(app,app.FitXLabel1.Text,app.FitYLabel1.Text,size(im,1),size(im,2));
                end
                
                % Plot the image
                app.T1Image.Position = [25,408,300,300];
                xlim(app.T1Image,[0, size(im,1) + 1]);
                ylim(app.T1Image,[0, size(im,2) + 1]);
                
                % Plot the T1 map
                imshow(rot90(im),[0 app.MaxT1EditField.Value],'Colormap',app.t1Cmap,'InitialMagnification','fit','Parent',app.T1Image);
                colorbar(app.T1Image,'east','Ticks',[]);
                
                % Set aspect ratio
                daspect(app.T1Image,[aratio 1 1]);

                % ROI display if present
                if app.roiFlag && ~isempty(app.roiPoints)
                    app.PlotPoints;
                end
                
                % ----- M0 map -----
                
                % Select the data for the M0 map
                im = app.imageM0;
                
                % Estimate a suitable scale
                scale = 2*median(nonzeros(app.imageM0(:)))+1;
                if isnan(scale) scale = 32767; end
                if scale==0 scale = 32767; end
                
                % Select which slice to show
                im = permute(im,app.maskOrient(app.FitOrientationSpinner.Value,:));
                im = squeeze(im(app.FitFrameEditField.Value,:,:,app.FitSliceEditField.Value));
                
                % If phase orientation = 1 rotate image (for viewing purposes only)
                if strcmp(app.FitXLabel1.Text,'X') && strcmp(app.FitYLabel1.Text,'Y') && app.parameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end
                
                % Plot the image
                app.M0Image.Position = [336,408,300,300];
                xlim(app.M0Image,[0, size(im,1) + 1]);
                ylim(app.M0Image,[0, size(im,2) + 1]);
                
                % Plot the T1 map
                imshow(rot90(im),[0 scale],'Colormap',gray,'InitialMagnification','fit','Parent',app.M0Image);
                
                % Set aspect ratio
                daspect(app.M0Image,[aratio 1 1]);
                
            end
            
            hold(app.T1Image,'off');
            hold(app.M0Image,'off');
            drawnow;
            
        end


        function PlotCross(app)

            % Image dimensions
            if strcmp(app.FitXLabel1.Text,'X') && strcmp(app.FitYLabel1.Text,'Y') && app.parameters.PHASE_ORIENTATION
                px = app.maskOrient(app.FitOrientationSpinner.Value,3);
                py = app.maskOrient(app.FitOrientationSpinner.Value,2);
                pz = app.maskOrient(app.FitOrientationSpinner.Value,4);
            else
                px = app.maskOrient(app.FitOrientationSpinner.Value,2);
                py = app.maskOrient(app.FitOrientationSpinner.Value,3);
                pz = app.maskOrient(app.FitOrientationSpinner.Value,4);
            end
            ysize = size(app.imageT1,py);

            % ----- Draw a cross-hair on the T1 map -----

            if app.autumnButton.Value == 1 || app.hotButton.Value == 1
                clr = 'g';
            else
                clr = 'r';
            end
            
            % Select point in current visible slice and orientation
            app.roiPoints = unique(app.roiPoints,'rows');
            pnts = app.roiPoints(:,[1 px py pz]);
            tColumn = pnts(:,1);
            zColumn = pnts(:,4);
            pnts = pnts(zColumn==app.FitSliceEditField.Value & tColumn==app.FitFrameEditField.Value, :);

            delete(app.cross);
            if strcmp(app.FitXLabel1.Text,'X') && strcmp(app.FitYLabel1.Text,'Y') && app.parameters.PHASE_ORIENTATION
                app.cross = drawcrosshair(app.T1Image, 'Position', [pnts(:,3) ysize-pnts(:,2)+1], 'Color', clr, 'LineWidth', 1);
            else
                app.cross = drawcrosshair(app.T1Image, 'Position', [pnts(:,2) ysize-pnts(:,3)+1], 'Color', clr, 'LineWidth', 1);
            end

        end


        function PlotPoints(app)

            % Image dimensions
            if strcmp(app.FitXLabel1.Text,'X') && strcmp(app.FitYLabel1.Text,'Y') && app.parameters.PHASE_ORIENTATION
                px = app.maskOrient(app.FitOrientationSpinner.Value,3);
                py = app.maskOrient(app.FitOrientationSpinner.Value,2);
                pz = app.maskOrient(app.FitOrientationSpinner.Value,4);
            else
                px = app.maskOrient(app.FitOrientationSpinner.Value,2);
                py = app.maskOrient(app.FitOrientationSpinner.Value,3);
                pz = app.maskOrient(app.FitOrientationSpinner.Value,4);
            end
            ysize = size(app.imageT1,py);

            % ----- Draw points on the T1 map -----

            if app.autumnButton.Value == 1 || app.hotButton.Value == 1
                clr = 'g';
            else
                clr = 'r';
            end

            % Select points in current visible slice and orientation
            app.roiPoints = unique(app.roiPoints,'rows');
            pnts = app.roiPoints(:,[1 px py pz]);
            tColumn = pnts(:,1);
            zColumn = pnts(:,4);
            pnts = pnts(zColumn==app.FitSliceEditField.Value & tColumn==app.FitFrameEditField.Value, :);
            
            % Plot the points
            hold(app.T1Image,'on');
            delete(app.roi);
            if strcmp(app.FitXLabel1.Text,'X') && strcmp(app.FitYLabel1.Text,'Y') && app.parameters.PHASE_ORIENTATION
                app.roi = plot(app.T1Image, pnts(:,3), ysize-pnts(:,2)+1, 's', 'MarkerSize', 4, 'Color', clr, 'MarkerFaceColor', clr);
            else
                app.roi = plot(app.T1Image, pnts(:,2), ysize-pnts(:,3)+1, 's', 'MarkerSize', 4, 'Color', clr, 'MarkerFaceColor', clr);
            end
            hold(app.T1Image,'off');
            
        end


        function PlotFits(app,data,t1,stdt1,m0)
         
            % ----- Signal versus flip-angle curve -----
            
            cla(app.T1FitFig);
            hold(app.T1FitFig,'on');

            fa = app.flipAngles;
            tr = app.parameters.tr;

            if m0 > 0 
                
                % data
                app.T1FitFig.Position = [27,11,285,204];
                xlim(app.T1FitFig,[0,max(fa(:))+5]);
                ylim(app.T1FitFig,[0,1.4*max(data(:))]);
                scatter(app.T1FitFig,fa,data);
                
                % fit
                a = 0 : 0.25: max(fa(:))+5;
                s = m0*sin(pi*a./180).*(1-exp(-tr/t1))./(1-cos(pi.*a/180)*exp(-tr/t1));
                plot(app.T1FitFig,a,s);
                
                % label
                if stdt1 > 0
                    app.FittedT1Label.Text = ['T1 = ',num2str(t1,'% 10.1f'),' ',char(177),' ',num2str(stdt1,'% 10.1f'),' ms'];
                else
                    app.FittedT1Label.Text = ['T1 = ',num2str(t1,'% 10.1f'),' ms'];
                end
                
            else
                
                % Show no data
                cla(app.T1FitFig);
                
                % label
                app.FittedT1Label.Text = '';
                
            end
            
            hold(app.T1FitFig,'off');
            
        
            % ----- DESPOT1 representation -----
            
            cla(app.DespotT1FitFig);
            hold(app.DespotT1FitFig,'on');
            
            if m0 > 0 
                
                % data
                x = data./tan(pi*fa/180);
                y = data./sin(pi*fa/180);
                app.DespotT1FitFig.Position = [336,23,282,204];
                scatter(app.DespotT1FitFig,x,y);
                xlim(app.DespotT1FitFig,[0.9*min(x),1.1*max(x)]);
              
                % fit
                a = 0 : 0.25: max(fa(:))+10;
                s = m0*sin(pi*a./180).*(1-exp(-tr/t1))./(1-cos(pi.*a/180)*exp(-tr/t1));
                da = s./tan(pi*a/180);
                da = da(~isnan(da));
                ds = s./sin(pi*a/180);
                ds = ds(~isnan(ds));
                plot(app.DespotT1FitFig,da,ds);
                
            else
                
                cla(app.DespotT1FitFig);
                
            end
            
            hold(app.DespotT1FitFig,'off');
            
        end % PlotFits


    end


    % ----------------------------------------------
    % Public functions, available outside the app
    % ----------------------------------------------


    methods (Access = public)
        
        
        function TextMessage(app, message)
            
            % Text messages in the app
            try
                app.MessageBox.Value = [message ; app.MessageBox.Value];
                app.MessageBox.Value = app.MessageBox.Value(1:50,1);
                drawnow;
            catch
            end
            
        end % TextMessage
        
        
        function ShowRegistrationProgress(app, progress)
            
            % Update the progress bar
            app.RegistrationProgressGauge.Value = progress;
            
        end % ShowRegistrationProgress
        
        
        function ShowFitProgress(app, progress)
            
            % Update the progress bar
            app.FitProgressGauge.Value = progress;
            
        end % ShowFitProgress
        
        
        % Status icon
        function SetStatus(app,status)
            
            switch status

                case 0
                    app.StatusImage.Position = [425,68,30,30];
                    app.StatusImage.ImageSource = 'statusokay.gif';
                    app.statusLabel.Text = 'OK';
                    app.statusLabel.FontColor = app.green;

                case 1
                    app.StatusImage.Position = [425,68,30,30];
                    app.StatusImage.ImageSource = 'statuswarning.gif';
                    app.statusLabel.Text = 'Warning';
                    app.statusLabel.FontColor = app.orange;
                    sound(app.warningSound,48000);

                case 2
                    app.StatusImage.Position = [425,68,30,30];
                    app.StatusImage.ImageSource = 'statuserror.gif';
                    app.statusLabel.Text = 'ERROR';
                    app.statusLabel.FontColor = app.brightred;
                    sound(app.errorSound,48000);

                case 3
                    app.StatusImage.Position = [428,74,24,24];
                    app.StatusImage.ImageSource = 'busy2.gif';
                    app.statusLabel.Text = 'BUSY';
                    app.statusLabel.FontColor = app.green;
                    
            end
            
            drawnow;
            
        end


    end


    % ----------------------------------------------------------------------------


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Disable unnecessary warnings
            warning off;
            clc;
            
            % Empty file import and export paths
            app.dataImportPath = '';
            app.dicomExportPath = '';
            app.niftiExportPath = '';
            app.gifExportPath = '';
            app.mrdFlag = true;
            
            % Start the parallel pool if it is not running already
            p = gcp('nocreate');
            if isempty(p)
                try
                    parpool(8);
                catch
                    parpool;
                end
            end
          
            % Add subfolders to search path
            if (~isdeployed)
                app.oldDir = pwd;
                info = dir(which('T1mapp.mlapp'));
                app.appDir = info.folder;
                cd(app.appDir);
                addpath(genpath(pwd));
                try
                    rmpath(genpath(strcat(pwd,filesep,'T1mapp/')));
                catch
                end
                try
                    rmpath(genpath(strcat(pwd,filesep,'T1mapp_resources/')));
                catch
                end
                try
                    rmpath(genpath(strcat(pwd,filesep,'standalone installers/')));
                catch
                end
            end
            
            % Set app layout
            app.SetAppLayoutProperties;
            
            % Add the bart path to the environment variable if available
            try
                if ismac
                    % [~,result] = system('uname -m');
                    [~,result] = system('sysctl -in sysctl.proc_translated');
                    if strcmp(strtrim(result),'1') % Running ROSETTA2, assuming arm architecture 
                        app.TextMessage('Apple arm version');
                    else
                        app.TextMessage('Apple Intel version');
                    end
                else
                    app.TextMessage('PC Intel version');
                end
            catch ME
                app.TextMessage(ME.message);
            end

            % Determine if BART is available by requesting its version number
            app.bartDetectedFlag = false;
            try
                bart(app,'version');
                if ~contains(app.bartVersion,'none')
                    app.bartDetectedFlag = true;
                end
            catch ME
                app.TextMessage(ME.message);
            end

            if app.bartDetectedFlag
                app.TextMessage('BART toolbox detected ...');
            else
                app.TextMessage('WARNING: BART toolbox not available ...');
                app.SetStatus(1);
            end
            
            % Some other settings
            app.validRecoFlag = false;
            app.validB1Flag = false;
            app.validDataFileFlag = false;
            app.validFitFlag = false;
            app.validMaskFlag = false;
            
            % OK
            app.SetStatus(0);
            
        end

        % Button pushed function: LoadMRDButton
        function LoadMRDButtonPushed(app, event)
            
            
            % Indicate busy
            SetLights(app,{'Yellow','Red','Red','Red','Red','Red','Red'});
            SetButtons(app,{'off','off','off','off','off','off','off','off'});
            app.SetStatus(3);

            app.validDataFileFlag = false;
            app.validFitFlag = false;
  
            % Check standard smis directory first if platform is pc, only 1x per session
            if ispc
                try
                    folderName = winqueryreg('HKEY_LOCAL_MACHINE','Software\MRS\MRSClinicalUI\Scan','MrdDirectory');
                    folderName = [folderName,filesep];
                    app.TextMessage('MRD directory found in Windows registry ...');
                    app.TextMessage(strcat('MRD directory =',{' '},folderName));
                catch ME
                    app.TextMessage(ME.message);
                    app.TextMessage('Trying standard MRD directory ...');
                    folderName = 'c:\smis\dev\MRD\';
                end
                if exist(folderName, 'dir') && app.mrdFlag
                    app.dataImportPath = folderName;
                    app.mrdFlag = false;
                end
            end
            
            % Select 1 MRD file which contains multiple flip-angles data
            try
                app.T1mappUIFigure.Visible = 'off';
                [mrdfile,app.dataImportPath] = uigetfile(strcat(app.dataImportPath,filesep,'*.MRD'));
                app.T1mappUIFigure.Visible = 'on';
            catch ME
                app.TextMessage(ME.message);
            end
      
            % File selected
            if ischar(mrdfile)
                
                % MRD file selected
                mrdfile = [app.dataImportPath,mrdfile];
                
                % Load the data
                app.TextMessage('Reading MRD file ...');
                [~, fName, ~] = fileparts(mrdfile);
                if contains(fName,'retro') || contains(fName,'p2roud')
                    centric = false;
                    [data,~,app.parameters] = Get_mrd_3D4(mrdfile,'seq','seq');
                    if isfield(app.parameters,'pe2_centric_on')
                        if app.parameters.pe2_centric_on
                            [data,~,app.parameters] = Get_mrd_3D4(mrdfile,'seq','cen');
                            centric = true;
                        end
                    end
                else
                    centric = true;
                    [data,~,app.parameters] = Get_mrd_3D4(mrdfile,'seq','cen');
                    if isfield(app.parameters,'pe2_centric_on')
                        if ~app.parameters.pe2_centric_on
                            [data,~,app.parameters] = Get_mrd_3D4(mrdfile,'seq','seq');
                            centric = false;
                        end
                    end
                end
                if centric
                    app.TextMessage('Centric phase encoding ...');
                else
                    app.TextMessage('Sequential phase encoding ...');
                end
                
                % Valid file check
                cond1 = false;
                cond2 = false;
                
                if isfield(app.parameters,'VFA_size')
                    if app.parameters.VFA_size > 2
                        cond1 = true;
                    else
                        app.TextMessage('ERROR: Number of flip-angles should be at least 3 ...');
                    end
                end

                if isfield(app.parameters,'NO_VIEWS_2')
                    if app.parameters.NO_VIEWS_2 >= 8
                        cond2 = true;
                    else
                        app.TextMessage('ERROR: NO_VIEWS_2 should be at least 8 ...');
                    end
                end
                
                app.validDataFileFlag = cond1 && cond2;
              
                % Continue with valid file 
                if app.validDataFileFlag

                    try

                        % Check for multiple echo times
                        if isfield(app.parameters,'NO_ECHOES')
                            if app.parameters.NO_ECHOES == 1
                                data1 = permute(data,[1 5 2 3 4]);
                            else
                                data1 = data;
                            end
                        else
                            app.parameters.NO_ECHOES = 1;
                            data1 = permute(data,[1 5 2 3 4]);
                        end

                        % 3D fft the data
                        app.TextMessage('Fast Fourier Transform ...');
                        images = zeros(size(data1));
                        for i=1:app.parameters.VFA_size
                            for j=1:app.parameters.NO_ECHOES
                                images(i,j,:,:,:) = abs(fft3reco(squeeze(data1(i,j,:,:,:))));
                            end
                        end

                        % Sort according to flip angles
                        app.TextMessage('Sorting the data in ascending flip-angle order ...');
                        app.image5D = zeros(size(images));
                        [app.flipAngles, index] = sort(app.parameters.VFA_angles(1:app.parameters.VFA_size));
                        app.flipAngles = app.flipAngles(1:size(images,1));
                        for i=1:size(images,1)
                            for j=1:app.parameters.NO_ECHOES
                                app.image5D(i,j,:,:,:) = images(index(i),j,:,:,:);
                            end
                        end

                        % Data = (flipangles, echoes, dimx, dimy, dimz)
                        app.image5D = flip(permute(app.image5D,[1,2,5,3,4]),3);

                        if contains(fName,'retro') 
                            app.image5D = flip(app.image5D,3);
                        end

                        % Normalize to convenient range
                        app.image5D = round(32767*app.image5D/max(app.image5D(:)));

                        % Sequence file name
                        if ~isfield(app.parameters,'PPL')
                            app.parameters.PPL = " ";
                        end
                        [~,app.parameters.tag,~] = fileparts(mrdfile);
                        if ismac
                            app.parameters.PPL = replace(app.parameters.PPL,'\','/');
                        end
                        [~,name,ext] = fileparts(app.parameters.PPL);
                        app.parameters.PPL = strcat(name,ext);

                        % Ready
                        app.validRecoFlag = true;
                        app.TextMessage('Data import ready ...');

                    catch ME

                        app.validDataFileFlag = false;
                        app.validRecoFlag = false;
                        app.TextMessage('ERROR: during data import ...');
                        app.TextMessage(ME.message);
                        
                    end
                    
                end

            end


            % Report on valid/invalid file selection
            if app.validDataFileFlag

                % Set the buttons for valid file
                app.SetLights({'Green','Red','Red','Red','Red','Red','Red'});
                app.SetButtons({'on','on','on','on','off','off','off','on'});

                % Set other parameters
                app.SetParametersFcn;
                app.OrientationSpinnerValueChanged;
                app.B1OrientationSpinnerValueChanged;
                app.SetStatus(0);
              
            else
                
                % Not a valid data file
                app.TextMessage('ERROR: Please select 3D data with at least 3 flip angles ...');
                app.validRecoFlag = false;
                
                % Set the buttons for invalid data file
                app.SetLights({'Red','Red','Red','Red','Red','Red','Red'});
                app.SetButtons({'on','off','off','off','off','off','off','on'});
                app.SetStatus(2);
                
            end
            
        end

        % Button pushed function: B1LoadMRDButton
        function B1LoadMRDButtonPushed(app, event)
           
            if app.validDataFileFlag

                % Valid B1 data file
                app.validB1Flag = false;

                % Select 1 MRD file which contains multiple flip-angles data for B1 mapping
                try
                    app.T1mappUIFigure.Visible = 'off';
                    [mrdfile,app.dataImportPath] = uigetfile(strcat(app.dataImportPath,filesep,'*.MRD'));
                    app.T1mappUIFigure.Visible = 'on';
                catch ME
                    app.TextMessage(ME.message);
                end

                % File selected
                try

                    if ischar(mrdfile)

                        % MRD file selected
                        mrdfile = [app.dataImportPath,mrdfile];

                        % Load the data
                        app.TextMessage('Reading MRD file ...');
                        [~, fName, ~] = fileparts(mrdfile);

                        if contains(fName,'retro') || contains(fName,'p2roud')
                            centric = false;
                            [data,~,app.B1Parameters] = Get_mrd_3D4(mrdfile,'seq','seq');
                            if isfield(app.B1Parameters,'pe2_centric_on')
                                if app.B1Parameters.pe2_centric_on
                                    [data,~,app.B1Parameters] = Get_mrd_3D4(mrdfile,'seq','cen');
                                    centric = true;
                                end
                            end
                        else
                            centric = true;
                            [data,~,app.B1Parameters] = Get_mrd_3D4(mrdfile,'seq','cen');
                            if isfield(app.B1Parameters,'pe2_centric_on')
                                if ~app.B1Parameters.pe2_centric_on
                                    [data,~,app.B1Parameters] = Get_mrd_3D4(mrdfile,'seq','seq');
                                    centric = false;
                                end
                            end
                        end

                        if centric
                            app.TextMessage('Centric phase encoding ...');
                        else
                            app.TextMessage('Sequential phase encoding ...');
                        end

                        % Valid file check
                        cond1 = false;
                        cond2 = false;

                        if isfield(app.B1Parameters,'VFA_size')
                            if app.B1Parameters.VFA_size > 2
                                cond1 = true;
                            else
                                app.TextMessage('ERROR: Number of flip-angles should be at least 3 ...');
                            end
                        end

                        if isfield(app.B1Parameters,'NO_VIEWS_2')
                            if app.B1Parameters.NO_VIEWS_2 >= 8
                                cond2 = true;
                            else
                                app.TextMessage('ERROR: NO_VIEWS_2 should be at least 8 ...');
                            end
                        end

                        if cond1 && cond2

                            % Seems valid B1 datafile
                            app.validB1Flag = true;

                            % 3D fft the data
                            app.TextMessage('Fast Fourier Transform ...');
                            faimages = zeros(size(data));
                            for i=1:app.B1Parameters.VFA_size
                                faimages(i,:,:,:) = abs(fft3reco(squeeze(data(i,:,:,:))));
                            end

                            % Data = (flipangles, dimx, dimy, dimz)
                            faimages = flip(permute(faimages,[1,4,2,3]),2);
                            app.B1FlipAngles = app.B1Parameters.VFA_angles(1:app.B1Parameters.VFA_size);

                            % Make the B1 map
                            B1im = fitB1mapFnc(faimages,app.B1FlipAngles);

                            % Resize to resolution of T1 map
                            app.imageB1 = [];
                            app.imageB1(:,:,:) = imresize3(squeeze(B1im(:,:,:)),[app.MatrixViewField1.Value,app.MatrixViewField2.Value,app.MatrixViewField3.Value],'lanczos3');

                            % Plot the B1 map
                            app.B1OrientationSpinnerValueChanged;
                            app.PlotB1Fig;

                            app.TextMessage('B1 map generated ...');

                        end

                    end

                catch ME

                    app.TextMessage(ME.message);
                    app.validB1Flag = false;

                end

            end

        end

        % Value changed function: SliceEditField
        function SliceEditFieldValueChanged(app, event)
            
            if app.validRecoFlag
                
                app.SliceSlider.Value = app.SliceEditField.Value;
                app.PlotImageFig;
                
            end
            
        end

        % Value changed function: SliceSlider
        function SliceSliderValueChanged(app, event)
            
            if app.validRecoFlag
                
                app.SliceEditField.Value = round(app.SliceSlider.Value);
                app.PlotImageFig;
                
            end
            
        end

        % Value changed function: B1SliceEditField
        function B1SliceEditFieldValueChanged(app, event)

            if app.validRecoFlag && app.validB1Flag

                app.B1SliceSlider.Value = app.B1SliceEditField.Value;
                app.PlotB1Fig;

            end

        end

        % Value changed function: B1SliceSlider
        function B1SliceSliderValueChanged(app, event)
            
            if app.validRecoFlag && app.validB1Flag
                
                app.B1SliceEditField.Value = round(app.B1SliceSlider.Value);
                app.PlotB1Fig;
                
            end
            
        end

        % Value changed function: FlipAngleEditField
        function FlipAngleEditFieldValueChanged(app, event)
             
            if app.validRecoFlag
                
                app.FlipAngleSlider.Value = app.FlipAngleEditField.Value;
                PlotImageFig(app);
                
            end
            
        end

        % Value changed function: FlipAngleSlider
        function FlipAngleSliderValueChanged(app, event)
            
            if app.validRecoFlag
                
                app.FlipAngleEditField.Value = round(app.FlipAngleSlider.Value);
                PlotImageFig(app);
                
            end
            
        end

        % Value changed function: FrameEditField
        function FrameEditFieldValueChanged(app, event)
            
            if app.validRecoFlag
                
                app.FrameSlider.Value = app.FrameEditField.Value;
                PlotImageFig(app);
                
            end
            
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            
            if app.validRecoFlag
                
                app.FrameEditField.Value = round(app.FrameSlider.Value);
                PlotImageFig(app);
                
            end
            
        end

        % Value changed function: OrientationSpinner
        function OrientationSpinnerValueChanged(app, event)
            
            if app.validRecoFlag
                
                % reset the limits to the appropriate bounds
                app.SliceEditField.Value = 1;
                app.SliceSlider.Value = 1;
                
                app.SliceEditField.Limits = [1 size(app.image5D,app.imageOrient(app.OrientationSpinner.Value,5))];
                app.SliceSlider.Limits = [1 size(app.image5D,app.imageOrient(app.OrientationSpinner.Value,5))];
                
                app.SliceEditField.Value = round(size(app.image5D,app.imageOrient(app.OrientationSpinner.Value,5))/2);
                app.SliceSlider.Value = round(size(app.image5D,app.imageOrient(app.OrientationSpinner.Value,5))/2);
               
                % plot the image
                app.PlotImageFig;
                
             end
            
        end

        % Value changed function: B1OrientationSpinner
        function B1OrientationSpinnerValueChanged(app, event)
            
            if app.validRecoFlag && app.validB1Flag
                
                % reset the limits to the appropriate bounds
                app.B1SliceEditField.Value = 1;
                app.B1SliceSlider.Value = 1;
                
                app.B1SliceEditField.Limits = [1 size(app.imageB1,app.B1Orient(app.B1OrientationSpinner.Value,3))];
                app.B1SliceSlider.Limits = [1 size(app.imageB1,app.B1Orient(app.B1OrientationSpinner.Value,3))];
                
                app.B1SliceEditField.Value = round(size(app.imageB1,app.B1Orient(app.B1OrientationSpinner.Value,3))/2);
                app.B1SliceSlider.Value = round(size(app.imageB1,app.B1Orient(app.B1OrientationSpinner.Value,3))/2);
               
                % plot the imag
                app.PlotB1Fig;
                
             end
            
        end

        % Value changed function: IntensitySlider
        function IntensitySliderValueChanged(app, event)
            
            if app.validRecoFlag
                
                PlotImageFig(app);
                
            end
        
        end

        % Button pushed function: RegisterButton
        function RegisterButtonPushed(app, event)
            
            if app.validRecoFlag
                
                app.TextMessage('Automatic image registration ...');
                
                % Indicate busy
                SetLights(app,{'Null','Yellow','Red','Red','Red','Red','Red'});
                SetButtons(app,{'off','off','off','off','off','off','off','off'});
                app.SetStatus(3);
                
                % Initialize progress gauge
                app.RegistrationProgressGauge.Value = 0;
               
                try
                    
                    % Register all slices to 1st echo time image
                    app.image5D = register_images_3DT1(app,app.image5D,app.maxIndx);
                    app.TextMessage('Automatic image registration is ready ...');
                    SetLights(app,{'Null','Green','Red','Red','Red','Red','Red'});
                    SetButtons(app,{'on','on','on','on','off','off','off','on'});
                    app.SetStatus(0);
                    
                catch ME
                    
                    % Failure
                    app.TextMessage('ERROR: during image registration ...');
                    app.TextMessage(ME.message);
                    SetLights(app,{'Null','Red','Red','Red','Red','Red','Red'});
                    SetButtons(app,{'on','on','on','on','off','off','off','on'});
                    app.SetStatus(2);
                    
                end
                
            end
            
        end

        % Button pushed function: SegmentButton
        function SegmentButtonPushed(app, event)
          
            if app.validRecoFlag
                
                app.TextMessage('Automatic image segmentation ...');
                
                % Indicate busy
                SetLights(app,{'Null','Null','Yellow','Red','Red','Red','Red'});
                SetButtons(app,{'off','off','off','off','off','off','off','off'});
                app.SetStatus(3);

                try

                    % Determine automatic threshold using the Otsu method
                    app.threshold = double(graythresh(mat2gray(squeeze(app.image5D(app.maxIndx,:,:,:,:)))) * max(squeeze(app.image5D(app.maxIndx,:))));

                    % Set the automatic mask for all slices
                    app.mask = apply_threshold_3D(app.image5D(app.maxIndx,:,:,:,:),app.threshold);
                    app.mask = reshape(app.mask,size(app.image5D,2),size(app.image5D,3),size(app.image5D,4),size(app.image5D,5));

                    % Display the threshold value for the current slice
                    app.ThresholdEditField.Value = round(app.threshold);
                    app.ThresholdSlider.Value = round(app.threshold);

                    % Mask is valid
                    app.validMaskFlag = true;

                    % Display the mask in the image
                    PlotImageFig(app);

                    % Reset buttons
                    SetLights(app,{'Null','Null','Green','Red','Red','Red','Red'});
                    SetButtons(app,{'on','on','on','on','off','off','off','on'});
                    app.SetStatus(0);

                catch ME

                    % Failure
                    app.validMaskFlag = false;
                    app.TextMessage('ERROR: during image segmentation ...');
                    app.TextMessage(ME.message);
                    SetLights(app,{'Null','Null','Red','Red','Red','Red','Red'});
                    SetButtons(app,{'on','on','on','on','off','off','off','on'});
                    app.SetStatus(2);

                end
                
            end
            
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            
            app.ThresholdSlider.Value = app.ThresholdEditField.Value;

            if app.validRecoFlag

                try

                    % Set the automatic mask for all slices
                    for i = 1:size(app.image5D,2)
                        app.mask(i,:,:,:) = apply_threshold_3D(squeeze(app.image5D(app.maxIndx,i,:,:,:)),app.ThresholdEditField.Value);
                    end
                    
                    % Mask is valid
                    app.validMaskFlag = true;

                    % Plot the images with the new mask
                    app.PlotImageFig;

                    % Reset buttons
                    SetLights(app,{'Null','Null','Green','Red','Red','Red','Red'});
                    SetButtons(app,{'on','on','on','on','off','off','off','on'});
                    app.SetStatus(0);

                catch ME

                    %Failure
                    app.validMaskFlag = false;
                    app.TextMessage('ERROR: during image segmentation ...');
                    app.TextMessage(ME.message);
                    SetLights(app,{'Null','Null','Red','Red','Red','Red','Red'});
                    SetButtons(app,{'on','on','on','on','off','off','off','on'});
                    app.SetStatus(2);

                end

            end
            
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            
            app.ThresholdEditField.Value = round(app.ThresholdSlider.Value);
            app.ThresholdEditFieldValueChanged;
            
        end

        % Button pushed function: ErodeButton
        function ErodeButtonPushed(app, event)
            
            if app.validRecoFlag
                
                try

                    % Morphological erosion
                    app.mask = 1-imerode(1-app.mask,[0 1 0; 1 1 1; 0 1 0]);

                    % Display the result;
                    app.PlotImageFig;

                catch ME
                    
                    app.TextMessage(ME.message);
                    
                end
                
                % Reset buttons
                SetLights(app,{'Null','Null','Green','Red','Red','Red','Red'});
                SetButtons(app,{'on','on','on','on','off','off','off','on'});
                app.SetStatus(0);
                
            end
        
        end

        % Button pushed function: DilateButton
        function DilateButtonPushed(app, event)
            
            if app.validRecoFlag
                
                try

                    % Morphological dilation
                    app.mask = 1-imdilate(1-app.mask,[0 1 0; 1 1 1; 0 1 0]);

                    % Display the result;
                    app.PlotImageFig;

                catch ME

                    app.TextMessage(ME.message);

                end
                
                % Reset buttons
                SetLights(app,{'Null','Null','Green','Red','Red','Red','Red'});
                SetButtons(app,{'on','on','on','on','off','off','off','on'});
                app.SetStatus(0);
                
            end
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            
            if app.validRecoFlag
                
                try

                    % Morphological open
                    app.mask = 1-imopen(1-app.mask,[0 1 0; 1 1 1; 0 1 0]);

                    % Display the result;
                    app.PlotImageFig;

                catch ME

                    app.TextMessage(ME.message);

                end
                
                % Reset buttons
                SetLights(app,{'Null','Null','Green','Red','Red','Red','Red'});
                SetButtons(app,{'on','on','on','on','off','off','off','on'});
                app.SetStatus(0);
                
            end
        
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
        
            if app.validRecoFlag
                
                try

                    % Morphological close
                    app.mask = 1-imclose(1-app.mask,[0 1 0; 1 1 1; 0 1 0]);

                    % Display the result;
                    app.PlotImageFig;

                catch ME

                    app.TextMessage(ME.message);

                end
                
                % Reset buttons
                SetLights(app,{'Null','Null','Green','Red','Red','Red','Red'});
                SetButtons(app,{'on','on','on','on','off','off','off','on'});
                app.SetStatus(0);
                
            end    
        
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
          
            if app.validRecoFlag
                
                % Disable segmentation buttons to prevent change during fit
                DisableSegmentButtons(app);
                SetLights(app,{'Null','Null','Null','Yellow','Red','Red','Red'});
                SetButtons(app,{'off','off','off','off','off','off','off','off'});
                app.SetStatus(3);

                try

                    % Progress gauge parameters
                    totalnumberofsteps = (app.FitRangeSlicesEndEditField.Value - app.FitRangeSlicesStartEditField.Value + 1)*(app.FitRangeFramesEndEditField.Value - app.FitRangeFramesStartEditField.Value + 1);
                    app.FitProgressGauge.Value = 0;
                    app.abortFitFlag = false;

                    app.EstimatedFitTimeViewField.Value = 'Calculating ...';
                    app.TextMessage('Fitting ...');

                    elapsedtime = 0;
                    cnt = 1;

                    for i = app.FitRangeSlicesStartEditField.Value:app.FitRangeSlicesEndEditField.Value  % Delta Z

                        for j = app.FitRangeFramesStartEditField.Value:app.FitRangeFramesEndEditField.Value % Delta Frames

                            tic;

                            % Fit function
                            [app.imageT1(j,:,:,i),app.imageM0(j,:,:,i)] = dotheT1fit_despot(squeeze(app.image5D(:,j,:,:,i)), ...
                                squeeze(app.mask(j,:,:,i)), app.flipAngles, app.parameters.tr, squeeze(app.imageB1(:,:,i)), app.B1MapCheckBox.Value);

                            % Update the fit progress gauge
                            app.FitProgressGauge.Value = round(100*(cnt/totalnumberofsteps));
                            cnt = cnt+1;

                            % Abort fit if abort button pressed
                            if app.abortFitFlag

                                app.TextMessage('Fitting aborted ...');

                                % Reset buttons
                                SetLights(app,{'Null','Null','Null','Red','Red','Red','Red'});
                                SetButtons(app,{'on','on','on','on','off','off','off','on'});
                                app.SetStatus(1);
                                drawnow;

                                break;
                            end

                            % Update the timing indicator
                            elapsedtime = elapsedtime + toc;
                            estimatedtotaltime = elapsedtime * totalnumberofsteps / cnt;
                            timeremaining = estimatedtotaltime * (totalnumberofsteps - cnt + 1) / totalnumberofsteps;
                            timeremaining(timeremaining<0) = 0;
                            app.EstimatedFitTimeViewField.Value = datestr(seconds(timeremaining),'MM:SS'); %#ok<*DATST> 
                            drawnow;

                        end
                        
                    end
                    
                    if ~app.abortFitFlag

                        app.TextMessage('Finished ... ');
                        app.EstimatedFitTimeViewField.Value = 'Finished ...';
                        app.validFitFlag = true;
                        app.FitOrientationSpinnerValueChanged;
                        app.AutoButtonPushed;

                        % Reset buttons
                        SetLights(app,{'Null','Null','Null','Green','Red','Red','Red'});
                        SetButtons(app,{'on','on','on','on','on','on','on','on'});
                        app.SetStatus(0);

                    end

                    % Re-enable the segmentation buttons
                    app.EnableSegmentButtons;

                catch ME
                    
                    % Failure;
                    app.TextMessage('ERROR: during fitting ...');
                    app.TextMessage(ME.message);
                    SetLights(app,{'Null','Null','Null','Green','Red','Red','Red'});
                    SetButtons(app,{'on','on','on','on','on','on','on','on'});
                    app.SetStatus(2);
                    app.EnableSegmentButtons;
                
                end
                
            end
            
        end

        % Value changed function: FitRangeSlicesEndEditField, 
        % ...and 1 other component
        function FitRangeSlicesStartOrEndEditFieldValueChanged(app, event)
            
            if app.FitRangeSlicesStartEditField.Value > app.FitRangeSlicesEndEditField.Value
                
                app.FitRangeSlicesStartEditField.Value = app.FitRangeSlicesEndEditField.Value;
                
            end
            
        end

        % Value changed function: FitRangeFramesEndEditField, 
        % ...and 1 other component
        function FitRangeFramesStartOrEndEditFieldValueChanged(app, event)
             
            if app.FitRangeFramesStartEditField.Value > app.FitRangeFramesEndEditField.Value
                
                app.FitRangeFramesStartEditField.Value = app.FitRangeFramesEndEditField.Value;
                
            end
            
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            
            app.abortFitFlag = true;
        
        end

        % Selection changed function: ColormapButtonGroup
        function ColormapButtonGroupSelectionChanged(app, event)
            
            if app.grayButton.Value == 1
                app.t1Cmap = gray;
            end
            
            if app.autumnButton.Value == 1
                app.t1Cmap = autumn;
            end
            
            if app.winterButton.Value == 1
                app.t1Cmap = winter;
            end
            
            if app.parulaButton.Value == 1
                app.t1Cmap = parula;
            end
            
            if app.hotButton.Value == 1
                app.t1Cmap = hot;
            end
            
            % Background should always be black
            app.t1Cmap(1,:) = [0 0 0];
            
            % Plot the maps with the new color map
            PlotMapsFig(app);
            
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
            
            if app.validFitFlag
                
                % Estimate a suitable scale for the T1 map
                % Auto-scale = 2 x mean of the T1 values in the image
                scale = round(2*mean(nonzeros(app.imageT1(:))));
                
                if isnan(scale) scale = 1000; end
                if scale==0 scale = 1000; end
                if scale>5000 scale = 5000; end
                app.MaxT1EditField.Value = scale;
                
                % plot the maps
                PlotMapsFig(app);
                
            end
            
        end

        % Value changed function: MaxT1EditField
        function MaxT1EditFieldValueChanged(app, event)
            
            % plot the maps
            PlotMapsFig(app);
            
        end

        % Value changed function: FitSliceEditField
        function FitSliceEditFieldValueChanged(app, event)
             
            if app.validFitFlag
                
                app.FitSliceSlider.Value = app.FitSliceEditField.Value;
                PlotMapsFig(app);
                
            end
            
        end

        % Value changed function: FitSliceSlider
        function FitSliceSliderValueChanged(app, event)
            
            if app.validFitFlag
                
                app.FitSliceEditField.Value = round(app.FitSliceSlider.Value);
                PlotMapsFig(app);
                
            end
             
        end

        % Window scroll wheel function: T1mappUIFigure
        function T1mappUIFigureWindowScrollWheel(app, event)

            verticalScrollCount = event.VerticalScrollCount;

            % Get the Position of the GUI and the position of the mouse
            set(0,'units','pixels');
            guiposition = get(app.T1mappUIFigure,'Position');
            cursorposition = get(0,'PointerLocation');
            Xpos = cursorposition(1)-guiposition(1);

            if Xpos < 705

                if app.validRecoFlag

                    if verticalScrollCount > 0
                        newslice = app.SliceEditField.Value + 1;
                        newslice(newslice>app.SliceEditField.Limits(2)) = app.SliceEditField.Limits(2);
                    else
                        newslice = app.SliceEditField.Value - 1;
                        newslice(newslice<1) = 1;
                    end

                    app.SliceEditField.Value = newslice;
                    app.SliceEditFieldValueChanged;

                end

            else

                if ~app.roiFlag

                    if app.validFitFlag

                        if verticalScrollCount > 0
                            newslice = app.FitSliceEditField.Value + 1;
                            newslice(newslice>app.FitSliceEditField.Limits(2)) = app.FitSliceEditField.Limits(2);
                        else
                            newslice = app.FitSliceEditField.Value - 1;
                            newslice(newslice<1) = 1;
                        end

                        app.FitSliceEditField.Value = newslice;
                        app.FitSliceEditFieldValueChanged;

                    end

                end

            end

            drawnow;

        end

        % Value changed function: FitFrameEditField
        function FitFrameEditFieldValueChanged(app, event)
             
            if app.validFitFlag
                
                app.FitFrameSlider.Value = app.FitFrameEditField.Value;
                PlotMapsFig(app);
                
            end
            
        end

        % Value changed function: FitFrameSlider
        function FitFrameSliderValueChanged(app, event)
            
            if app.validFitFlag
                
                app.FitFrameEditField.Value = round(app.FitFrameSlider.Value);
                PlotMapsFig(app);
                
            end
            
        end

        % Value changed function: FitOrientationSpinner
        function FitOrientationSpinnerValueChanged(app, event)
            
            if app.validFitFlag
                
                % reset the limits to the appropriate bounds
                app.FitSliceEditField.Value = 1;
                app.FitSliceSlider.Value = 1;
                
                app.FitSliceEditField.Limits = [1 size(app.imageT1,app.maskOrient(app.FitOrientationSpinner.Value,4))];
                app.FitSliceSlider.Limits = [1 size(app.imageT1,app.maskOrient(app.FitOrientationSpinner.Value,4))];
                
                app.FitSliceEditField.Value = round(size(app.imageT1,app.maskOrient(app.FitOrientationSpinner.Value,4))/2);
                app.FitSliceSlider.Value = round(size(app.imageT1,app.maskOrient(app.FitOrientationSpinner.Value,4))/2);
               
                % plot the image
                PlotMapsFig(app);
                
             end
            
        end

        % Image clicked function: Image1
        function T1mapImageClicked(app, event)
        
            if app.validFitFlag

                try

                    % Get the Position of the GUI and the position of the mouse click
                    set(0,'units','pixels');
                    guiposition = get(app.T1mappUIFigure,'Position');
                    cursorposition = get(0,'PointerLocation');

                    % Position of the cursor click in the image window
                    imagepos = [743,449]; % position of bottom-left corner of image in the app ~ (745,449)
                    Xpos = cursorposition(1)-guiposition(1)-imagepos(1);
                    Ypos = cursorposition(2)-guiposition(2)-imagepos(2);

                    % Image orientation and aspect ratio
                    if strcmp(app.FitXLabel1.Text,'X') && strcmp(app.FitYLabel1.Text,'Y') && app.parameters.PHASE_ORIENTATION
                        px = app.maskOrient(app.FitOrientationSpinner.Value,3);
                        py = app.maskOrient(app.FitOrientationSpinner.Value,2);
                        pz = app.maskOrient(app.FitOrientationSpinner.Value,4);
                    else
                        px = app.maskOrient(app.FitOrientationSpinner.Value,2);
                        py = app.maskOrient(app.FitOrientationSpinner.Value,3);
                        pz = app.maskOrient(app.FitOrientationSpinner.Value,4);
                    end
              
                    aratio = app.parameters.FOV3D(px-1)/app.parameters.FOV3D(py-1);
                    xsize = size(app.imageT1,px);
                    ysize = size(app.imageT1,py);

                    % Correct for non-square image
                    imsize = 289;
                    if aratio >= 1
                        xcor = (Xpos/imsize) * xsize;
                        ycor = (Ypos/imsize) * ysize * aratio - (0.5 * (aratio-1) * ysize);
                    else
                        xcor = (Xpos/imsize) * xsize * (1/aratio) - (0.5 * ((1/aratio)-1) * xsize);
                        ycor = (Ypos/imsize) * ysize;
                    end

                    % Round to nearest integer
                    xcor = round(xcor);
                    ycor = round(ycor);

                    % Keep the values within matrix boundaries
                    xcor(xcor<1) = 1;
                    ycor(ycor<1) = 1;
                    xcor(xcor>xsize) = xsize;
                    ycor(ycor>ysize) = ysize;

                    % Current slice and time frame
                    zcor = app.FitSliceEditField.Value;
                    timeFrame = app.FitFrameEditField.Value;

                    % Inverse orientation
                    pi = [1 px py pz];
                    pi = pi(:,pi);

                    if app.roiFlag

                        % Paint ROI
                        for i = -1:1
                            for j = -1:1
                                if (xcor+i>0) && (ycor+j>0) && (xcor+i<=xsize) && (ycor+j<=ysize)
                                    addPoint = [timeFrame xcor+i ycor+j zcor];
                                    addPoint = addPoint(:,pi);
                                    app.roiPoints = [app.roiPoints; addPoint];
                                end
                            end
                        end
                        app.PlotPoints;

                    else

                        % Plot a cross
                        addPoint = [timeFrame xcor ycor zcor];
                        app.roiPoints = addPoint(:,pi);
                        app.PlotCross;

                    end

                    % Select the raw image data in the selected point / ROI
                    msk = app.mask;
                    msk = reshape(msk,[1 size(msk)]);
                    imdata = app.image5D.*msk;
                    for i = 1:size(app.roiPoints,1)
                        if strcmp(app.FitXLabel1.Text,'X') && strcmp(app.FitYLabel1.Text,'Y') && app.parameters.PHASE_ORIENTATION
                            rawdatas(i,:) =  imdata(:,app.roiPoints(i,1),ysize-app.roiPoints(i,3)+1,app.roiPoints(i,2),app.roiPoints(i,4)); %#ok<*AGROW> 
                        else
                            rawdatas(i,:) =  imdata(:,app.roiPoints(i,1),app.roiPoints(i,2),app.roiPoints(i,3),app.roiPoints(i,4)); %#ok<*AGROW> 
                        end
                    end

                    % Mean of the data points in the ROI
                    if size(rawdatas,1)>1
                        rawdatas(rawdatas==0) = nan;
                        rawdata = mean(rawdatas,'omitnan');             % trick to exclude ROI pixels in background
                    else
                        rawdata = rawdatas;
                    end

                    % Determine mean T1 and M0 in point / ROI
                    for i = 1:size(app.roiPoints,1)
                        if strcmp(app.FitXLabel1.Text,'X') && strcmp(app.FitYLabel1.Text,'Y') && app.parameters.PHASE_ORIENTATION
                            t1values(i) = app.imageT1(app.roiPoints(i,1),ysize-app.roiPoints(i,3)+1,app.roiPoints(i,2),app.roiPoints(i,4));
                            m0values(i) = app.imageM0(app.roiPoints(i,1),ysize-app.roiPoints(i,3)+1,app.roiPoints(i,2),app.roiPoints(i,4));
                        else
                            t1values(i) = app.imageT1(app.roiPoints(i,1),app.roiPoints(i,2),app.roiPoints(i,3),app.roiPoints(i,4));
                            m0values(i) = app.imageM0(app.roiPoints(i,1),app.roiPoints(i,2),app.roiPoints(i,3),app.roiPoints(i,4));
                        end
                    end

                    r1values = 1./t1values;
                    r1values = nonzeros(r1values);
                    r1values(isnan(r1values)) = [];
                    r1values(isinf(r1values)) = [];
                    t1value = 1/mean(r1values);                         % T1 = 1 / mean(R1 values in pixels)
                    stdt1value = t1value*std(r1values)/mean(r1values);  % error propagation with division

                    m0value = mean(nonzeros(m0values));
                    m0value(isnan(m0value)) = 0;

                    % Plot the raw data and fitted curves
                    app.PlotFits(rawdata,t1value,stdt1value,m0value);

                catch ME

                    app.TextMessage('ERROR: Something went wrong during ROI selection ...');
                    app.TextMessage(ME.message);

                end

            end
            
        end

        % Button pushed function: ROIbutton
        function ROIbuttonButtonPushed(app, event)
            
             % Toggle between ROI drawing and cross-hair drawing
             
             if strcmp(app.ROIbutton.Text,'ROI')
                 
                 app.ROIbutton.Text = 'Cross';
                 app.TextMessage('ROI drawing selected ...');
                 app.EraseButton.Visible = 'on';
                 app.roiFlag = true;
             
             else  
                 
                 app.ROIbutton.Text = 'ROI';
                 app.TextMessage('Cross-hair selected ...');
                 app.EraseButton.Visible = 'off';
                 app.roiFlag = false;
                 
             end
            
             app.roiPoints = [];
             delete(app.roi);
             delete(app.cross);
             PlotMapsFig(app);
            
        end

        % Button pushed function: EraseButton
        function EraseButtonButtonPushed(app, event)

            app.roiPoints = [];
            delete(app.roi);
            PlotMapsFig(app);

        end

        % Button pushed function: ExportDCMButton
        function ExportDCMButtonPushed(app, event)
            
            if app.validFitFlag
                
                % Indicate busy
                SetLights(app,{'Null','Null','Null','Null','Yellow','Null','Null'});
                SetButtons(app,{'off','off','off','off','off','off','off','off'});
                app.SetStatus(3);
                
                % Search for dicom file
                app.TextMessage('Searching for DICOM information ...');
                
                % Base / data / 343 / DICOM / 8455 /
                dcm_spath{1} = replace(app.dataImportPath,'MRD','Data');
                dcmpos = strfind(dcm_spath{1},filesep);
                dcmpos = dcmpos(end-1);
                dcm_spath{1} = insertBefore(dcm_spath{1},dcmpos,[filesep,'DICOM']);
                
                % Base / data / 343 / DICOM / 8455 / 1 /
                dcm_spath{2} = [dcm_spath{1},'1',filesep];
                
                % Base / data / 343 / DICOM / 8455 / 2 /
                dcm_spath{3} = [dcm_spath{1},'2',filesep];
                
                % Check if one of the directories contains dicom files
                dcm_exist = false;
                app.TextMessage('Searching for DICOM info in the following locations ...');
                for i=1:length(dcm_spath)
                    app.TextMessage(dcm_spath{i});
                    if ~isempty(dir([dcm_spath{i},filesep,'*.dcm']))
                        dcm_path = dcm_spath{i};
                        dcm_exist = true;
                        app.TextMessage('Found DICOM info ...');
                        filesepIdx = strfind(dcm_path, filesep);
                        app.dicomExportPath = dcm_path(1:filesepIdx(end-4));
                        break;
                    end
                end
                
                % Ask for directory
                try
                    app.T1mappUIFigure.Visible = 'off';
                    app.dicomExportPath = uigetdir(app.dicomExportPath,'Dicom export path');
                    app.T1mappUIFigure.Visible = 'on';
                    drawnow;
                catch ME
                    app.TextMessage(ME.message);
                end
                
                % Check if directory exists
                ready = true;
                if ~ischar(app.dicomExportPath)   % user cancel
                    ready = false;
                    app.dicomExportPath = '';
                else
                    [~, Attrib] = fileattrib(app.dicomExportPath);
                    if ~any(Attrib.UserWrite)   % check if directory is writable
                        ready = false;
                    end
                end
               
                % Export to Dicom
                if ready

                    try

                        app.TextMessage('Exporting to Dicom  ...');

                        if dcm_exist

                            % Dicom export using original dicom tags
                            export_dicom_3DT1_dcm(dcm_path,app.dicomExportPath,app.imageM0,app.imageT1,app.parameters);

                        else

                            % Dicom export without dicom tag information
                            export_dicom_3DT1(app.dicomExportPath,app.imageM0,app.imageT1,app.parameters,app.parameters.tag);

                        end

                        % Success
                        app.TextMessage('Dicom export finished ...');
                        app.SetStatus(0);

                        % Reset buttons
                        SetLights(app,{'Null','Null','Null','Null','Green','Null','Null'});
                        SetButtons(app,{'on','on','on','on','on','on','on','on'});


                    catch ME

                        % Failure
                        app.TextMessage('ERROR: Something went wrong during DICOM export ...');
                        app.TextMessage(ME.message);
                        app.SetStatus(2);

                        % Reset buttons
                        SetLights(app,{'Null','Null','Null','Null','Red','Null','Null'});
                        SetButtons(app,{'on','on','on','on','on','on','on','on'});


                    end

                else

                    % Invalid directory
                    app.TextMessage('ERROR: Not a valid DICOM directory ...');
                    app.SetStatus(2);

                    % Reset buttons
                    SetLights(app,{'Null','Null','Null','Null','Red','Null','Null'});
                    SetButtons(app,{'on','on','on','on','on','on','on','on'});
                    
                end
                
            end
            
        end

        % Button pushed function: ExportNIFTIButton
        function ExportNIFTIButtonPushed(app, event)
            
            if app.validFitFlag
                
                % Indicate busy
                SetLights(app,{'Null','Null','Null','Null','Null','Yellow','Null'});
                SetButtons(app,{'off','off','off','off','off','off','off','off'});
                app.SetStatus(3);
                
                % Ask for directory
                app.T1mappUIFigure.Visible = 'off';
                app.niftiExportPath = uigetdir(app.niftiExportPath,'NIFTI export path');
                app.T1mappUIFigure.Visible = 'on';
                drawnow;
                
                % Check if directory exists
                ready = true;
                if ~ischar(app.niftiExportPath)   % user cancel
                    ready = false;
                    app.niftiExportPath = '';
                else
                    [~, Attrib] = fileattrib(app.niftiExportPath);
                    if ~any(Attrib.UserWrite)   % check if directory is writable
                        ready = false;
                    end
                end

                % Export to NIFTI
                if ready

                    try

                        app.TextMessage('Exporting to NIFTI ...');

                        % Image dimensions
                        imT1 = cast(app.imageT1,'single');
                        imM0 = cast(app.imageM0,'single');
                        imT1 = permute(imT1,[2 3 4 1]);
                        imM0 = permute(imM0,[2 3 4 1]);

                        % Phase orientation
                        if isfield(app.parameters, 'PHASE_ORIENTATION')
                            if app.parameters.PHASE_ORIENTATION == 1
                                app.TextMessage('INFO: phase orientation = 1');
                                imT1 = permute(rot90(permute(imT1,[2 1 3 4]),1),[2 1 3 4]);
                                imM0 = permute(rot90(permute(imM0,[2 1 3 4]),1),[2 1 3 4]);
                                FOVx = app.parameters.FOV3D(2);
                                FOVy = app.parameters.FOV3D(1);
                                FOVz = app.parameters.FOV3D(3);
                            else
                                FOVx = app.parameters.FOV3D(1);
                                FOVy = app.parameters.FOV3D(2);
                                FOVz = app.parameters.FOV3D(3);
                            end
                        end
                        imT1 = flip(imT1,1);
                        imM0 = flip(imM0,1);
                        [dimx,dimy,dimz,nrframes] = size(imT1);

                        % Set some parameters
                        load('niftiinfo.mat'); %#ok<LOAD> 
                        info.Filemoddate = datestr(datetime);
                        info.Filesize = nrframes*dimx*dimy*dimz*32;
                        info.Datatype = 'single';
                        info.BitsPerPixel = 32;
                        info.Version = 1;

                        % Frames yes or no
                        app.parameters.timeperframe = 0.100/nrframes;
                        if nrframes>1
                            info.ImageSize = [dimx dimy dimz nrframes];
                            info.PixelDimensions = [FOVx/dimx FOVy/dimy FOVz/dimz app.parameters.timeperframe];
                        else
                            info.ImageSize = [dimx dimy dimz];
                            info.PixelDimensions = [FOVx/dimx FOVy/dimy FOVz/dimz];
                        end

                        % Dimensions
                        info.raw.dim = [3 dimx dimy dimz nrframes 1 1 1 1];
                        info.raw.pixdim = [FOVx/dimx FOVy/dimy FOVz/dimz app.parameters.timeperframe 0 0 0 0];
                        info.vox_offset = 0;

                        % Export NIFTI
                        info.Filename = 'im3D_T1.nii';
                        niftiwrite(imT1,[app.niftiExportPath,filesep,'im3D_T1.nii'],info);
                        info.Filename = 'im3D_M0.nii';
                        niftiwrite(imM0,[app.niftiExportPath,filesep,'im3D_M0.nii'],info);

                        % Success
                        app.TextMessage('NIFTI export finished ...');
                        app.SetStatus(0);

                        % Reset buttons
                        SetLights(app,{'Null','Null','Null','Null','Null','Green','Null'});
                        SetButtons(app,{'on','on','on','on','on','on','on','on'});

                    catch ME

                        % Failure
                        app.TextMessage('Error: Something went wrong during NIFTI export ...');
                        app.TextMessage(ME.message);
                        app.SetStatus(2);

                        % Reset buttons
                        SetLights(app,{'Null','Null','Null','Null','Null','Red','Null'});
                        SetButtons(app,{'on','on','on','on','on','on','on','on'});

                    end

                else

                    % Not a valid directory
                    app.TextMessage('ERROR: Not a valid directory ...');
                    app.SetStatus(2);

                    % Reset buttons
                    SetLights(app,{'Null','Null','Null','Null','Null','Red','Null'});
                    SetButtons(app,{'on','on','on','on','on','on','on','on'});

                end

            end
           
        end

        % Button pushed function: ExportGIFButton
        function ExportGIFButtonPushed(app, event)
            
            if app.validFitFlag
                
                % Indicate busy
                SetLights(app,{'Null','Null','Null','Null','Null','Null','Yellow'});
                SetButtons(app,{'off','off','off','off','off','off','off','off'});
                app.SetStatus(3);
                
                % Ask for directory
                app.T1mappUIFigure.Visible = 'off';
                app.gifExportPath = uigetdir(app.gifExportPath,'GIF export path');
                app.T1mappUIFigure.Visible = 'on';
                drawnow;
                
                % Check if directory exists
                ready = true;
                if ~ischar(app.gifExportPath)     % user cancel
                    ready = false;
                    app.gifExportPath = '';
                else
                    [~, Attrib] = fileattrib(app.gifExportPath);
                    if ~any(Attrib.UserWrite)       % check if directory is writable
                        ready = false;
                    end
                end
                
                % Export to animated gif
                if ready
                    
                    try

                        % T1 map in viewing orientation
                        imT1 = app.imageT1;
                        imT1 = permute(imT1,app.maskOrient(app.FitOrientationSpinner.Value,:));

                        % M0 map in viewing orientation
                        imM0 = app.imageM0;
                        imM0 = permute(imM0,app.maskOrient(app.FitOrientationSpinner.Value,:));

                        % Image aspect ratio
                        aratio = CalcAspectratioFnc(app,app.FitXLabel1.Text,app.FitYLabel1.Text,size(imT1,2),size(imT1,3));

                        app.TextMessage('Exporting to GIF ...');

                        export_gif_3DT1(app,app.parameters,app.gifExportPath,imT1,imM0,app.parameters.tag,app.MaxT1EditField.Value,app.t1Cmap,aratio);

                        % Success
                        app.TextMessage('GIF export finished ...');
                        app.SetStatus(0);

                        % Reset buttons
                        SetLights(app,{'Null','Null','Null','Null','Null','Null','Green'});
                        SetButtons(app,{'on','on','on','on','on','on','on','on'});

                    catch ME

                        % Failure
                        app.TextMessage('Error: Something went wrong during GIF export ...');
                        app.TextMessage(ME.message);
                        app.SetStatus(2);

                        % Reset buttons
                        SetLights(app,{'Null','Null','Null','Null','Null','Null','Red'});
                        SetButtons(app,{'on','on','on','on','on','on','on','on'});
                        
                    end
                    
                else
                    
                    % Not a valid directory
                    app.TextMessage('Error: Not a valid directory ...');
                    app.SetStatus(2);
                    
                    % Reset buttons
                    SetLights(app,{'Null','Null','Null','Null','Null','Null','Red'});
                    SetButtons(app,{'on','on','on','on','on','on','on','on'});
                    
                end
                
            end
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            
            % Remove subfolders from search path
            if (~isdeployed)
                info = dir(which('T1mapp.mlapp'));
                app.appDir = info.folder;
                rmpath(genpath(app.appDir));
                cd(app.oldDir);
            end
            
            % Exit
            app.delete;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.T1mappUIFigure = uifigure('Visible', 'off');
            app.T1mappUIFigure.Name = 'T1mapp';
            app.T1mappUIFigure.Icon = fullfile(pathToMLAPP, 'T1icon.png');
            app.T1mappUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @T1mappUIFigureWindowScrollWheel, true);
            app.TitleLabel = uilabel(app.T1mappUIFigure);
            app.TitleLabel.Text = '3D RF-spoiled gradient-echo T1 mapping';
            app.CreditsLabel = uilabel(app.T1mappUIFigure);
            app.CreditsLabel.Text = 'Gustav Strijkers, Amsterdam UMC, Version X.X.X';
            app.TulpImage = uiimage(app.T1mappUIFigure);
            app.MRSImage = uiimage(app.T1mappUIFigure);
            app.DashboardTabGroup = uitabgroup(app.T1mappUIFigure);
            app.DashboardTab = uitab(app.DashboardTabGroup);
            app.DashboardTab.Title = 'Dashboard';
            app.LoadMRDButton = uibutton(app.DashboardTab, 'push');
            app.LoadMRDButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMRDButtonPushed, true);
            app.LoadMRDButton.Text = 'Load MRD';
            app.RegisterButton = uibutton(app.DashboardTab, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterButtonPushed, true);
            app.RegisterButton.Text = 'Register';
            app.SegmentButton = uibutton(app.DashboardTab, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentButtonPushed, true);
            app.SegmentButton.Text = 'Segment';
            app.RegistrationProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.RegistrationProgressGauge.MajorTicks = [0 25 50 75 100];
            app.FitButton = uibutton(app.DashboardTab, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.ExportDCMButton = uibutton(app.DashboardTab, 'push');
            app.ExportDCMButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDCMButtonPushed, true);
            app.ExportDCMButton.Text = 'Export DCM';
            app.ExportNIFTIButton = uibutton(app.DashboardTab, 'push');
            app.ExportNIFTIButton.ButtonPushedFcn = createCallbackFcn(app, @ExportNIFTIButtonPushed, true);
            app.ExportNIFTIButton.Text = 'Export NIFTI';
            app.ExportGIFButton = uibutton(app.DashboardTab, 'push');
            app.ExportGIFButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGIFButtonPushed, true);
            app.ExportGIFButton.Text = 'Export GIF';
            app.ExitButton = uibutton(app.DashboardTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = '    Exit';
            app.FitProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.FitProgressGauge.MajorTicks = [0 25 50 75 100];
            app.AbortButton = uibutton(app.DashboardTab, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Text = 'Abort';
            app.Lamp1 = uilamp(app.DashboardTab);
            app.Lamp2 = uilamp(app.DashboardTab);
            app.Lamp3 = uilamp(app.DashboardTab);
            app.Lamp4 = uilamp(app.DashboardTab);
            app.Lamp5 = uilamp(app.DashboardTab);
            app.Lamp6 = uilamp(app.DashboardTab);
            app.Lamp7 = uilamp(app.DashboardTab);
            app.FitRangeSlicesEndEditField = uieditfield(app.DashboardTab, 'numeric');
            app.FitRangeSlicesEndEditField.Limits = [1 Inf];
            app.FitRangeSlicesEndEditField.ValueChangedFcn = createCallbackFcn(app, @FitRangeSlicesStartOrEndEditFieldValueChanged, true);
            app.FitRangeSlicesEndEditField.Value = 1;
            app.Label = uilabel(app.DashboardTab);
            app.Label.Text = 'ΔΖ';
            app.FitRangeSlicesStartEditField = uieditfield(app.DashboardTab, 'numeric');
            app.FitRangeSlicesStartEditField.Limits = [1 Inf];
            app.FitRangeSlicesStartEditField.ValueDisplayFormat = '%.0f';
            app.FitRangeSlicesStartEditField.ValueChangedFcn = createCallbackFcn(app, @FitRangeSlicesStartOrEndEditFieldValueChanged, true);
            app.FitRangeSlicesStartEditField.Value = 1;
            app.FitRangeFramesEndEditField = uieditfield(app.DashboardTab, 'numeric');
            app.FitRangeFramesEndEditField.Limits = [1 Inf];
            app.FitRangeFramesEndEditField.ValueChangedFcn = createCallbackFcn(app, @FitRangeFramesStartOrEndEditFieldValueChanged, true);
            app.FitRangeFramesEndEditField.Value = 1;
            app.FramesLabel = uilabel(app.DashboardTab);
            app.FramesLabel.Text = 'ΔFrames';
            app.FitRangeFramesStartEditField = uieditfield(app.DashboardTab, 'numeric');
            app.FitRangeFramesStartEditField.Limits = [1 Inf];
            app.FitRangeFramesStartEditField.ValueDisplayFormat = '%.0f';
            app.FitRangeFramesStartEditField.ValueChangedFcn = createCallbackFcn(app, @FitRangeFramesStartOrEndEditFieldValueChanged, true);
            app.FitRangeFramesStartEditField.Value = 1;
            app.EstimatedtimeremainingEditFieldLabel = uilabel(app.DashboardTab);
            app.EstimatedtimeremainingEditFieldLabel.Text = 'Estimated time remaining';
            app.EstimatedFitTimeViewField = uieditfield(app.DashboardTab, 'text');
            app.EstimatedFitTimeViewField.Editable = 'off';
            app.minsecLabel = uilabel(app.DashboardTab);
            app.minsecLabel.Text = 'min:sec';
            app.MessagesTabGroup = uitabgroup(app.T1mappUIFigure);
            app.MessagesTab = uitab(app.MessagesTabGroup);
            app.MessagesTab.Title = 'Messages';
            app.MessageBox = uitextarea(app.MessagesTab);
            app.MessageBox.Editable = 'off';
            app.BackgroundBox = uitextarea(app.MessagesTab);
            app.BackgroundBox.Editable = 'off';
            app.StatusImage = uiimage(app.MessagesTab);
            app.statusLabel = uilabel(app.MessagesTab);
            app.statusLabel.Text = 'status';
            app.SequenceParametersTabGroup = uitabgroup(app.T1mappUIFigure);
            app.SequenceParametersTab = uitab(app.SequenceParametersTabGroup);
            app.SequenceParametersTab.Title = 'Sequence Parameters';
            app.MatrixViewField2 = uieditfield(app.SequenceParametersTab, 'numeric');
            app.MatrixViewField2.ValueDisplayFormat = '%.0f';
            app.MatrixViewField2.Editable = 'off';
            app.MatrixViewField3 = uieditfield(app.SequenceParametersTab, 'numeric');
            app.MatrixViewField3.ValueDisplayFormat = '%.0f';
            app.MatrixViewField3.Editable = 'off';
            app.FOVViewField2 = uieditfield(app.SequenceParametersTab, 'numeric');
            app.FOVViewField2.ValueDisplayFormat = '%.0f';
            app.FOVViewField2.Editable = 'off';
            app.FOVViewField3 = uieditfield(app.SequenceParametersTab, 'numeric');
            app.FOVViewField3.ValueDisplayFormat = '%.0f';
            app.FOVViewField3.Editable = 'off';
            app.XLabelFOV = uilabel(app.SequenceParametersTab);
            app.XLabelFOV.Text = 'X';
            app.YLabelFOV = uilabel(app.SequenceParametersTab);
            app.YLabelFOV.Text = 'Y';
            app.ZLabelFOV = uilabel(app.SequenceParametersTab);
            app.ZLabelFOV.Text = 'Z';
            app.TRmsLabel = uilabel(app.SequenceParametersTab);
            app.TRmsLabel.Text = 'TR (ms)';
            app.TRViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.TRViewField.Editable = 'off';
            app.TEmsLabel = uilabel(app.SequenceParametersTab);
            app.TEmsLabel.Text = 'TE (ms)';
            app.TEViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.TEViewField.Editable = 'off';
            app.SequenceEditFieldLabel = uilabel(app.SequenceParametersTab);
            app.SequenceEditFieldLabel.Text = 'Sequence';
            app.SequenceViewField = uieditfield(app.SequenceParametersTab, 'text');
            app.SequenceViewField.Editable = 'off';
            app.MatrixEditFieldLabel = uilabel(app.SequenceParametersTab);
            app.MatrixEditFieldLabel.Text = 'Matrix';
            app.MatrixViewField1 = uieditfield(app.SequenceParametersTab, 'numeric');
            app.MatrixViewField1.ValueDisplayFormat = '%.0f';
            app.MatrixViewField1.Editable = 'off';
            app.FOVmmLabel = uilabel(app.SequenceParametersTab);
            app.FOVmmLabel.Text = 'FOV (mm)';
            app.FOVViewField1 = uieditfield(app.SequenceParametersTab, 'numeric');
            app.FOVViewField1.ValueDisplayFormat = '%.0f';
            app.FOVViewField1.Editable = 'off';
            app.FlipanglesEditFieldLabel = uilabel(app.SequenceParametersTab);
            app.FlipanglesEditFieldLabel.Text = 'Flip angles';
            app.FlipAnglesViewField = uieditfield(app.SequenceParametersTab, 'text');
            app.FlipAnglesViewField.Editable = 'off';
            app.RawImagesTabGroup = uitabgroup(app.T1mappUIFigure);
            app.RawImagesTab = uitab(app.RawImagesTabGroup);
            app.RawImagesTab.Title = 'Raw Images';
            app.RawImage = uiaxes(app.RawImagesTab);
            app.RawImage.XTick = [];
            app.RawImage.YTick = [];
            app.ImageSelectorPanel = uipanel(app.RawImagesTab);
            app.ImageSelectorPanel.Title = 'Image Selector';
            app.SliceEditField = uieditfield(app.ImageSelectorPanel, 'numeric');
            app.SliceEditField.Limits = [1 256];
            app.SliceEditField.ValueDisplayFormat = '%.0f';
            app.SliceEditField.ValueChangedFcn = createCallbackFcn(app, @SliceEditFieldValueChanged, true);
            app.SliceEditField.Value = 1;
            app.SliceSlider = uislider(app.ImageSelectorPanel);
            app.SliceSlider.Limits = [1 256];
            app.SliceSlider.MajorTicks = [];
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @SliceSliderValueChanged, true);
            app.SliceSlider.MinorTicks = [];
            app.SliceSlider.Value = 1;
            app.flipangleEditFieldLabel = uilabel(app.ImageSelectorPanel);
            app.flipangleEditFieldLabel.Text = 'flip-angle';
            app.FlipAngleEditField = uieditfield(app.ImageSelectorPanel, 'numeric');
            app.FlipAngleEditField.Limits = [1 256];
            app.FlipAngleEditField.ValueDisplayFormat = '%.0f';
            app.FlipAngleEditField.ValueChangedFcn = createCallbackFcn(app, @FlipAngleEditFieldValueChanged, true);
            app.FlipAngleEditField.Value = 1;
            app.FlipAngleSlider = uislider(app.ImageSelectorPanel);
            app.FlipAngleSlider.Limits = [1 256];
            app.FlipAngleSlider.MajorTicks = [];
            app.FlipAngleSlider.ValueChangedFcn = createCallbackFcn(app, @FlipAngleSliderValueChanged, true);
            app.FlipAngleSlider.MinorTicks = [];
            app.FlipAngleSlider.Value = 1;
            app.OrientationSpinnerLabel = uilabel(app.ImageSelectorPanel);
            app.OrientationSpinnerLabel.Text = 'Orientation';
            app.OrientationSpinner = uispinner(app.ImageSelectorPanel);
            app.OrientationSpinner.Limits = [1 3];
            app.OrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @OrientationSpinnerValueChanged, true);
            app.OrientationSpinner.Value = 1;
            app.SliceLabel = uilabel(app.ImageSelectorPanel);
            app.SliceLabel.Text = 'Slice';
            app.FrameSlider = uislider(app.ImageSelectorPanel);
            app.FrameSlider.Limits = [1 256];
            app.FrameSlider.MajorTicks = [];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.MinorTicks = [];
            app.FrameSlider.Value = 1;
            app.frameEditFieldLabel = uilabel(app.ImageSelectorPanel);
            app.frameEditFieldLabel.Text = 'frame';
            app.FrameEditField = uieditfield(app.ImageSelectorPanel, 'numeric');
            app.FrameEditField.Limits = [1 256];
            app.FrameEditField.ValueDisplayFormat = '%.0f';
            app.FrameEditField.ValueChangedFcn = createCallbackFcn(app, @FrameEditFieldValueChanged, true);
            app.FrameEditField.Value = 1;
            app.SegmentationPanel = uipanel(app.RawImagesTab);
            app.SegmentationPanel.Title = 'Segmentation';
            app.ThresholdEditFieldLabel = uilabel(app.SegmentationPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.SegmentationPanel, 'numeric');
            app.ThresholdEditField.Limits = [0 Inf];
            app.ThresholdEditField.ValueDisplayFormat = '%.0f';
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdSlider = uislider(app.SegmentationPanel);
            app.ThresholdSlider.Limits = [1 32676];
            app.ThresholdSlider.MajorTicks = [];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.MinorTicks = [];
            app.ThresholdSlider.Value = 1;
            app.ErodeButton = uibutton(app.SegmentationPanel, 'push');
            app.ErodeButton.ButtonPushedFcn = createCallbackFcn(app, @ErodeButtonPushed, true);
            app.ErodeButton.Text = 'Erode';
            app.DilateButton = uibutton(app.SegmentationPanel, 'push');
            app.DilateButton.ButtonPushedFcn = createCallbackFcn(app, @DilateButtonPushed, true);
            app.DilateButton.Text = 'Dilate';
            app.CloseButton = uibutton(app.SegmentationPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.SegmentationPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.IntensitySlider = uislider(app.RawImagesTab);
            app.IntensitySlider.Limits = [0 2];
            app.IntensitySlider.MajorTicks = [];
            app.IntensitySlider.ValueChangedFcn = createCallbackFcn(app, @IntensitySliderValueChanged, true);
            app.IntensitySlider.MinorTicks = [];
            app.IntensitySlider.Value = 1;
            app.IntensityLabel = uilabel(app.RawImagesTab);
            app.IntensityLabel.Text = 'Intensity';
            app.ImageLabel = uilabel(app.RawImagesTab);
            app.ImageLabel.Text = 'Image';
            app.XLabel = uilabel(app.RawImagesTab);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.RawImagesTab);
            app.YLabel.Text = 'Y';
            app.Image3 = uiimage(app.RawImagesTab);
            app.B1Tab = uitab(app.RawImagesTabGroup);
            app.B1Tab.Title = 'B1';
            app.B1SliceEditField = uieditfield(app.B1Tab, 'numeric');
            app.B1SliceEditField.Limits = [1 256];
            app.B1SliceEditField.ValueDisplayFormat = '%.0f';
            app.B1SliceEditField.ValueChangedFcn = createCallbackFcn(app, @B1SliceEditFieldValueChanged, true);
            app.B1SliceEditField.Value = 1;
            app.B1SliceSlider = uislider(app.B1Tab);
            app.B1SliceSlider.Limits = [1 256];
            app.B1SliceSlider.MajorTicks = [];
            app.B1SliceSlider.ValueChangedFcn = createCallbackFcn(app, @B1SliceSliderValueChanged, true);
            app.B1SliceSlider.MinorTicks = [];
            app.B1SliceSlider.Value = 1;
            app.B1SliceLabel = uilabel(app.B1Tab);
            app.B1SliceLabel.Text = 'Slice';
            app.B1LoadMRDButton = uibutton(app.B1Tab, 'push');
            app.B1LoadMRDButton.ButtonPushedFcn = createCallbackFcn(app, @B1LoadMRDButtonPushed, true);
            app.B1LoadMRDButton.Text = 'Load B1 MRD';
            app.B1MapCheckBox = uicheckbox(app.B1Tab);
            app.B1MapCheckBox.Text = 'Use B1 map';
            app.B1OrientationSpinnerLabel = uilabel(app.B1Tab);
            app.B1OrientationSpinnerLabel.Text = 'Orientation';
            app.B1OrientationSpinner = uispinner(app.B1Tab);
            app.B1OrientationSpinner.Limits = [1 3];
            app.B1OrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @B1OrientationSpinnerValueChanged, true);
            app.B1OrientationSpinner.Value = 1;
            app.B1YLabel = uilabel(app.B1Tab);
            app.B1YLabel.Text = 'Y';
            app.B1XLabel = uilabel(app.B1Tab);
            app.B1XLabel.Text = 'X';
            app.WORKINPROGRESSLabel = uilabel(app.B1Tab);
            app.WORKINPROGRESSLabel.Text = 'WORK IN PROGRESS !!';
            app.FitResultsTabGroup = uitabgroup(app.T1mappUIFigure);
            app.FitResultsTab = uitab(app.FitResultsTabGroup);
            app.FitResultsTab.Title = 'Fit Results';
            app.T1FitFig = uiaxes(app.FitResultsTab);
            xlabel(app.T1FitFig, 'α')
            ylabel(app.T1FitFig, 'S')
            app.T1FitFig.XLim = [0 100];
            app.T1FitFig.XTick = [0 10 20 30 40 50 60 70 80 90 100];
            app.T1FitFig.YTick = [];
            app.DespotT1FitFig = uiaxes(app.FitResultsTab);
            xlabel(app.DespotT1FitFig, 'S / tan(α)')
            ylabel(app.DespotT1FitFig, 'S / sin(α)')
            app.DespotT1FitFig.XLim = [0 100];
            app.DespotT1FitFig.XTick = [0 10 20 30 40 50 60 70 80 90 100];
            app.DespotT1FitFig.YTick = [];
            app.M0Image = uiaxes(app.FitResultsTab);
            app.M0Image.XTick = [];
            app.M0Image.YTick = [];
            app.T1Image = uiaxes(app.FitResultsTab);
            app.T1Image.XTick = [];
            app.T1Image.YTick = [];
            app.EraseButton = uibutton(app.FitResultsTab, 'push');
            app.EraseButton.ButtonPushedFcn = createCallbackFcn(app, @EraseButtonButtonPushed, true);
            app.EraseButton.Visible = 'off';
            app.EraseButton.Text = 'Erase';
            app.ROIbutton = uibutton(app.FitResultsTab, 'push');
            app.ROIbutton.ButtonPushedFcn = createCallbackFcn(app, @ROIbuttonButtonPushed, true);
            app.ROIbutton.Text = 'ROI';
            app.Image2 = uiimage(app.FitResultsTab);
            app.Image1 = uiimage(app.FitResultsTab);
            app.Image1.ImageClickedFcn = createCallbackFcn(app, @T1mapImageClicked, true);
            app.FitYLabel2 = uilabel(app.FitResultsTab);
            app.FitYLabel2.Text = 'Y';
            app.FitXLabel2 = uilabel(app.FitResultsTab);
            app.FitXLabel2.Text = 'X';
            app.FitXLabel1 = uilabel(app.FitResultsTab);
            app.FitXLabel1.Text = 'X';
            app.M0Label = uilabel(app.FitResultsTab);
            app.M0Label.Text = 'M0';
            app.T1Label = uilabel(app.FitResultsTab);
            app.T1Label.Text = 'T1';
            app.FitImageSelectorPanel = uipanel(app.FitResultsTab);
            app.FitImageSelectorPanel.Title = 'Image Selector';
            app.FitSliceEditField = uieditfield(app.FitImageSelectorPanel, 'numeric');
            app.FitSliceEditField.Limits = [1 256];
            app.FitSliceEditField.ValueDisplayFormat = '%.0f';
            app.FitSliceEditField.ValueChangedFcn = createCallbackFcn(app, @FitSliceEditFieldValueChanged, true);
            app.FitSliceEditField.Value = 1;
            app.FitSliceSlider = uislider(app.FitImageSelectorPanel);
            app.FitSliceSlider.Limits = [1 256];
            app.FitSliceSlider.MajorTicks = [];
            app.FitSliceSlider.ValueChangedFcn = createCallbackFcn(app, @FitSliceSliderValueChanged, true);
            app.FitSliceSlider.MinorTicks = [];
            app.FitSliceSlider.Value = 1;
            app.OrientationSpinner_2Label = uilabel(app.FitImageSelectorPanel);
            app.OrientationSpinner_2Label.Text = 'Orientation';
            app.FitOrientationSpinner = uispinner(app.FitImageSelectorPanel);
            app.FitOrientationSpinner.Limits = [1 3];
            app.FitOrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @FitOrientationSpinnerValueChanged, true);
            app.FitOrientationSpinner.Value = 1;
            app.FitSliceLabel = uilabel(app.FitImageSelectorPanel);
            app.FitSliceLabel.Text = 'Slice';
            app.FitFrameEditField = uieditfield(app.FitImageSelectorPanel, 'numeric');
            app.FitFrameEditField.Limits = [1 256];
            app.FitFrameEditField.ValueDisplayFormat = '%.0f';
            app.FitFrameEditField.ValueChangedFcn = createCallbackFcn(app, @FitFrameEditFieldValueChanged, true);
            app.FitFrameEditField.Value = 1;
            app.FitFrameSlider = uislider(app.FitImageSelectorPanel);
            app.FitFrameSlider.Limits = [1 256];
            app.FitFrameSlider.MajorTicks = [];
            app.FitFrameSlider.ValueChangedFcn = createCallbackFcn(app, @FitFrameSliderValueChanged, true);
            app.FitFrameSlider.MinorTicks = [];
            app.FitFrameSlider.Value = 1;
            app.FitFrameLabel = uilabel(app.FitImageSelectorPanel);
            app.FitFrameLabel.Text = 'frame';
            app.ColormapButtonGroup = uibuttongroup(app.FitResultsTab);
            app.ColormapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColormapButtonGroupSelectionChanged, true);
            app.ColormapButtonGroup.Title = 'Color map';
            app.grayButton = uiradiobutton(app.ColormapButtonGroup);
            app.grayButton.Text = 'gray';
            app.autumnButton = uiradiobutton(app.ColormapButtonGroup);
            app.autumnButton.Text = 'autumn';
            app.winterButton = uiradiobutton(app.ColormapButtonGroup);
            app.winterButton.Text = 'winter';
            app.parulaButton = uiradiobutton(app.ColormapButtonGroup);
            app.parulaButton.Text = 'parula';
            app.parulaButton.Value = true;
            app.hotButton = uiradiobutton(app.ColormapButtonGroup);
            app.hotButton.Text = 'hot';
            app.MaxT1EditField = uieditfield(app.ColormapButtonGroup, 'numeric');
            app.MaxT1EditField.Limits = [1 5000];
            app.MaxT1EditField.ValueDisplayFormat = '%.0f';
            app.MaxT1EditField.ValueChangedFcn = createCallbackFcn(app, @MaxT1EditFieldValueChanged, true);
            app.MaxT1EditField.Value = 2000;
            app.MaxmsLabel = uilabel(app.ColormapButtonGroup);
            app.MaxmsLabel.Text = 'Max (ms)';
            app.AutoButton = uibutton(app.ColormapButtonGroup, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Text = 'Auto';
            app.SignalFunctionImage = uiimage(app.FitResultsTab);
            app.FitYLabel1 = uilabel(app.FitResultsTab);
            app.FitYLabel1.Text = 'Y';
            app.SignalflipangleLabel = uilabel(app.FitResultsTab);
            app.SignalflipangleLabel.Text = 'Signal - flip-angle';
            app.DESPOT1Label = uilabel(app.FitResultsTab);
            app.DESPOT1Label.Text = 'DESPOT1';
            app.FittedT1Label = uilabel(app.FitResultsTab);
            app.FittedT1Label.Text = 'T1';
            % Show the figure after all components are created
            app.T1mappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = T1mapp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.T1mappUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.T1mappUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.T1mappUIFigure)
        end
    end
end