classdef T1mapping < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        T1mappingUIFigure            matlab.ui.Figure
        DashboardPanel               matlab.ui.container.Panel
        LoadDataButton               matlab.ui.control.Button
        MRSolutionsCheckBox          matlab.ui.control.CheckBox
        BrukerCheckBox               matlab.ui.control.CheckBox
        RegisterButton               matlab.ui.control.Button
        SegmentButton                matlab.ui.control.Button
        RegistrationProgressGauge    matlab.ui.control.LinearGauge
        FitButton                    matlab.ui.control.Button
        ExportDicomButton            matlab.ui.control.Button
        ExportNIFTIButton            matlab.ui.control.Button
        ExportGIFButton              matlab.ui.control.Button
        ExitButton                   matlab.ui.control.Button
        RegistrationProgressLabel    matlab.ui.control.Label
        FitProgressGauge             matlab.ui.control.LinearGauge
        FitProgressLabel             matlab.ui.control.Label
        AbortButton                  matlab.ui.control.Button
        Lamp1                        matlab.ui.control.Lamp
        Lamp2                        matlab.ui.control.Lamp
        Lamp3                        matlab.ui.control.Lamp
        Lamp4                        matlab.ui.control.Lamp
        Lamp5                        matlab.ui.control.Lamp
        Lamp6                        matlab.ui.control.Lamp
        Lamp7                        matlab.ui.control.Lamp
        XRangeLabel                  matlab.ui.control.Label
        FitRangeStartEditField       matlab.ui.control.NumericEditField
        FitRangeEndEditField         matlab.ui.control.NumericEditField
        TitleLabel                   matlab.ui.control.Label
        RawimagesPanel               matlab.ui.container.Panel
        RawImage                     matlab.ui.control.UIAxes
        ImageSelectorPanel           matlab.ui.container.Panel
        SliceEditField               matlab.ui.control.NumericEditField
        SliceSlider                  matlab.ui.control.Slider
        ImageEditFieldLabel          matlab.ui.control.Label
        ImageEditField               matlab.ui.control.NumericEditField
        ImageSlider                  matlab.ui.control.Slider
        OrientationSpinnerLabel      matlab.ui.control.Label
        OrientationSpinner           matlab.ui.control.Spinner
        SliceLabel                   matlab.ui.control.Label
        SegmentationPanel            matlab.ui.container.Panel
        ThresholdEditFieldLabel      matlab.ui.control.Label
        ThresholdEditField           matlab.ui.control.NumericEditField
        ThresholdSlider              matlab.ui.control.Slider
        ErodeButton                  matlab.ui.control.Button
        DilateButton                 matlab.ui.control.Button
        CloseButton                  matlab.ui.control.Button
        OpenButton                   matlab.ui.control.Button
        IntensitySlider              matlab.ui.control.Slider
        IntensityLabel               matlab.ui.control.Label
        ImageLabel                   matlab.ui.control.Label
        XLabel                       matlab.ui.control.Label
        YLabel                       matlab.ui.control.Label
        CreditsLabel                 matlab.ui.control.Label
        TulpImage                    matlab.ui.control.Image
        MessagesPanel                matlab.ui.container.Panel
        MessageBox                   matlab.ui.control.TextArea
        SequenceparametersPanel      matlab.ui.container.Panel
        TRmsLabel                    matlab.ui.control.Label
        TRViewField                  matlab.ui.control.NumericEditField
        TEmsLabel                    matlab.ui.control.Label
        TEViewField                  matlab.ui.control.NumericEditField
        SequenceEditFieldLabel       matlab.ui.control.Label
        SequenceViewField            matlab.ui.control.EditField
        MatrixEditFieldLabel         matlab.ui.control.Label
        MatrixViewField1             matlab.ui.control.NumericEditField
        MatrixViewField2             matlab.ui.control.NumericEditField
        MatrixViewField3             matlab.ui.control.NumericEditField
        FOVViewField2                matlab.ui.control.NumericEditField
        FOVViewField3                matlab.ui.control.NumericEditField
        FOVmmLabel                   matlab.ui.control.Label
        FOVViewField1                matlab.ui.control.NumericEditField
        FlipanglesEditFieldLabel     matlab.ui.control.Label
        FlipAnglesViewField          matlab.ui.control.EditField
        XLabel_2                     matlab.ui.control.Label
        YLabel_2                     matlab.ui.control.Label
        ZLabel                       matlab.ui.control.Label
        FitresultsPanel              matlab.ui.container.Panel
        M0Image                      matlab.ui.control.UIAxes
        T1Label                      matlab.ui.control.Label
        M0Label                      matlab.ui.control.Label
        FitImageSelectorPanel        matlab.ui.container.Panel
        FitSliceEditField            matlab.ui.control.NumericEditField
        FitSliceSlider               matlab.ui.control.Slider
        OrientationSpinner_2Label_2  matlab.ui.control.Label
        FitOrientationSpinner        matlab.ui.control.Spinner
        FitSliceLabel                matlab.ui.control.Label
        ColormapButtonGroup          matlab.ui.container.ButtonGroup
        grayButton                   matlab.ui.control.RadioButton
        autumnButton                 matlab.ui.control.RadioButton
        winterButton                 matlab.ui.control.RadioButton
        parulaButton                 matlab.ui.control.RadioButton
        hotButton                    matlab.ui.control.RadioButton
        MaxT1EditField               matlab.ui.control.NumericEditField
        MaxmsLabel                   matlab.ui.control.Label
        AutoButton                   matlab.ui.control.Button
        T1FitFig                     matlab.ui.control.UIAxes
        DespotT1FitFig               matlab.ui.control.UIAxes
        SignalFunctionImage          matlab.ui.control.Image
        FitXLabel1                   matlab.ui.control.Label
        FitXLabel2                   matlab.ui.control.Label
        FitYLabel1                   matlab.ui.control.Label
        FitYLabel2                   matlab.ui.control.Label
        T1Image                      matlab.ui.control.UIAxes
        Image                        matlab.ui.control.Image
        SignalflipangleLabel         matlab.ui.control.Label
        DESPOT1Label                 matlab.ui.control.Label
        FittedT1Label                matlab.ui.control.Label
    end


    %% ----------------------------------------------------------------------------
    %
    %   3D T1 mapping
    %
    %   Gustav Strijkers
    %   Amsterdam UMC
    %
    %
    %   Version 1.0
    %
    %   - initial version
    %
    %
    %
    %% ----------------------------------------------------------------------------


    properties (Access = private)
        
        valid_datafile              % valid data file true or false
        valid_reco                  % valid reconstruction true or false
        valid_fit                   % valid fit data true or false
        valid_mask                  % valid mask true or false
        bart_detected               % bart toolbox detected true or false
        data_import_path            % data import path
        dicom_export_path           % dicom export path
        nifti_export_path           % nifti export path
        gif_export_path             % gif export path
        mrdparameters               % mrd file parameters
        seqpar                      % sequence parameters
        image4D                     % image data (flipangles, x , y , z);
        imageT1                     % 3D T1 map (x, y, z)
        imageM0                     % 3D M0 map (x, y, z)
        t1cmap                      % T1 color map
        flipangles                  % flip angles
        image_orient                % image orientations
        image_orient_label          % image orientation text labels
        mask_orient                 % mask orientation
        threshold                   % image threshold for segmentation
        mask                        % image mask for segmentation
        maxidx                      % number of the flip angle image with highest intensity, used for segmentation
        abort_fit                   % abort fit true or false
        appdir                      % Application directory
        olddir                      % Starting directory
        
    end


    % ----------------------------------------------------------------------------


    methods (Access = private)
        
        function SetLights(app,code)
            
            if ~strcmp(code{1},'Null') app.Lamp1.Color = code{1}; end
            if ~strcmp(code{2},'Null') app.Lamp2.Color = code{2}; end
            if ~strcmp(code{3},'Null') app.Lamp3.Color = code{3}; end
            if ~strcmp(code{4},'Null') app.Lamp4.Color = code{4}; end
            if ~strcmp(code{5},'Null') app.Lamp5.Color = code{5}; end
            if ~strcmp(code{6},'Null') app.Lamp6.Color = code{6}; end
            if ~strcmp(code{7},'Null') app.Lamp7.Color = code{7}; end
            drawnow;
            
        end
        
        
        function result = GetLights(app)
            
            result{1} = app.Lamp1.Color;
            result{2} = app.Lamp2.Color;
            result{3} = app.Lamp3.Color;
            result{4} = app.Lamp4.Color;
            result{5} = app.Lamp5.Color;
            result{6} = app.Lamp6.Color;
            result{7} = app.Lamp7.Color;
            
        end
        
        
        function SetButtons(app,code)
            
            if ~strcmp(code{1},'Null') app.LoadDataButton.Enable = code{1}; end
            if ~strcmp(code{2},'Null') app.RegisterButton.Enable = code{2}; end
            if ~strcmp(code{3},'Null') app.SegmentButton.Enable = code{3}; end
            if ~strcmp(code{4},'Null') app.FitButton.Enable = code{4}; end
            if ~strcmp(code{5},'Null') app.ExportDicomButton.Enable = code{5}; end
            if ~strcmp(code{6},'Null') app.ExportNIFTIButton.Enable = code{6}; end
            if ~strcmp(code{7},'Null') app.ExportGIFButton.Enable = code{7}; end
            if ~strcmp(code{8},'Null') app.ExitButton.Enable = code{8}; end
            drawnow;
            
        end
        
        
        function result = GetButtons(app)
            
            result{1} = app.LoadDataButton.Enable;
            result{2} = app.RegisterButton.Enable;
            result{3} = app.SegmentButton.Enable;
            result{4} = app.FitButton.Enable;
            result{5} = app.ExportDicomButton.Enable;
            result{6} = app.ExportNIFTIButton.Enable;
            result{7} = app.ExportGIFButton.Enable;
            result{8} = app.ExitButton.Enable;
            
        end
        
        
        function DisableSegmentButtons(app)
            
            app.SegmentButton.Enable = 'off';
            app.ThresholdEditField.Enable = 'off';
            app.ThresholdSlider.Enable = 'off';
            app.ErodeButton.Enable = 'off';
            app.DilateButton.Enable = 'off';
            app.CloseButton.Enable = 'off';
            app.OpenButton.Enable = 'off';
            
        end
        
        
        function EnableSegmentButtons(app)
            
            app.SegmentButton.Enable = 'on';
            app.ThresholdEditField.Enable = 'on';
            app.ThresholdSlider.Enable = 'on';
            app.ErodeButton.Enable = 'on';
            app.DilateButton.Enable = 'on';
            app.CloseButton.Enable = 'on';
            app.OpenButton.Enable = 'on';
            
        end


        function SetParametersFcn(app)
            
            % Show the sequence parameters in the app
            app.SequenceViewField.Value = app.seqpar.PPL;
            app.TRViewField.Value = app.seqpar.tr;
            app.TEViewField.Value = app.seqpar.te;
            app.MatrixViewField1.Value = app.seqpar.matrix(1);
            app.MatrixViewField2.Value = app.seqpar.matrix(2);
            app.MatrixViewField3.Value = app.seqpar.matrix(3);
            app.FOVViewField1.Value = app.seqpar.FOV3D(1);
            app.FOVViewField2.Value = app.seqpar.FOV3D(2);
            app.FOVViewField3.Value = app.seqpar.FOV3D(3);
            app.FlipAnglesViewField.Value = num2str(app.flipangles);
            
            % Image orientation labels
            app.image_orient = [1 2 3 4; 1 3 2 4 ; 1 4 2 3 ];
            app.image_orient_label = ['X' 'Y' 'Z' ; 'Y' 'X' 'Z' ; 'Z' 'X' 'Y'];
            app.mask_orient = [1 2 3; 2 1 3 ; 3 1 2 ];
            
            % Limits to some values
            app.ImageEditField.Value = 1;
            app.ImageEditField.Limits = [1 length(app.flipangles)];
            app.ImageSlider.Value = 1;
            app.ImageSlider.Limits = [1 length(app.flipangles)];
            app.ThresholdEditField.Value = 1;
            app.ThresholdEditField.Limits = [1 32767];
            app.ThresholdSlider.Value = 1;
            app.ThresholdSlider.Limits = [1 32767];
            app.FitRangeStartEditField.Limits = [1 app.seqpar.matrix(1)];
            app.FitRangeStartEditField.Value = 1;
            app.FitRangeEndEditField.Limits = [1 app.seqpar.matrix(1)];
            app.FitRangeEndEditField.Value = app.seqpar.matrix(1);
            
            % Determine which flip angle has the highest intensity
            for i = 1:length(app.flipangles)
                avgs(i) = mean(app.image4D(i,:));
            end
            [~,app.maxidx] = max(avgs);
            app.ImageEditField.Value = app.maxidx;
            app.ImageSlider.Value = app.maxidx;
            
            % Initialize fitting mask
            app.mask = ones([size(app.image4D,2),size(app.image4D,3),size(app.image4D,4)]);
            
            % Initialize the T1 and M0 maps
            app.imageT1 = zeros([size(app.image4D,2),size(app.image4D,3),size(app.image4D,4)]);
            app.imageM0 = zeros([size(app.image4D,2),size(app.image4D,3),size(app.image4D,4)]);
            app.t1cmap = gray;
            cla(app.RawImage);
            cla(app.T1Image);
            cla(app.M0Image);
            cla(app.T1FitFig);
            cla(app.DespotT1FitFig);
            
            
        end % SetParametersFcn


        function result = CalcAspectratioFnc(app,dim1label,dim2label,sizex,sizey)
        
            if strcmp(dim1label,'X') L1 = app.seqpar.FOV3D(1); end
            if strcmp(dim1label,'Y') L1 = app.seqpar.FOV3D(2); end    
            if strcmp(dim1label,'Z') L1 = app.seqpar.FOV3D(3); end
            if strcmp(dim2label,'X') L2 = app.seqpar.FOV3D(1); end
            if strcmp(dim2label,'Y') L2 = app.seqpar.FOV3D(2); end    
            if strcmp(dim2label,'Z') L2 = app.seqpar.FOV3D(3); end 
             
            result = (L2/L1)*(sizex/sizey);
            
        end % Aspectratio


        function PlotImageFig(app)
            
            cla(app.RawImage);
            hold(app.RawImage,'on');
            
            if app.valid_reco
                
                % Define the colormap
                % Since it is not possible to have 2 colormaps in 1 figure, the
                % colormap is split in a grayscale part [0 1] and a red-color overlay part [1 2]
                cmap1 = [linspace(0,1,128)',linspace(0,1,128)',linspace(0,1,128)'];
                cmap2 = [linspace(0,1,128)', zeros(128,2)];
                cmap = [cmap1;cmap2];
                cmap(cmap > 1) = 1;
                
                % Select the data
                im = app.image4D;
                
                % Estimate a suitable scale
                scale = 1 + max(im(:)) * (2-app.IntensitySlider.Value);
                
                % Select which slice to show
                im = permute(im,app.image_orient(app.OrientationSpinner.Value,:));
                im = squeeze(im(app.ImageEditField.Value,app.SliceEditField.Value,:,:));
                
                % Label the orientations
                app.SliceLabel.Text = app.image_orient_label(app.OrientationSpinner.Value,1);
                app.XLabel.Text = app.image_orient_label(app.OrientationSpinner.Value,2);
                app.YLabel.Text = app.image_orient_label(app.OrientationSpinner.Value,3);
                                
                % Image aspect ratio
                aratio = CalcAspectratioFnc(app,app.XLabel.Text,app.YLabel.Text,size(im,1),size(im,2));
                
                % Background image
                app.RawImage.Position = [13,60,300,300];
                xlim(app.RawImage,[0, size(im,1) + 1]);
                ylim(app.RawImage,[0, size(im,2) + 1]);
                im = rot90(im)/scale; im(im>1)=0.9999;
                backgroundimage = imshow(im,[0 2],'Colormap',cmap,'InitialMagnification','fit','Parent',app.RawImage);
                               
                % Overlay image
                if app.valid_mask
                    overlay = permute(app.mask,app.mask_orient(app.OrientationSpinner.Value,:));
                    overlay = squeeze(overlay(app.SliceEditField.Value,:,:));
                    overlayimage = imshow(rot90(2 - overlay),[0 2],'Colormap',cmap,'InitialMagnification','fit','Parent',app.RawImage);
                    alpha = 0.7-0.7*rot90(overlay);
                    set(overlayimage,'AlphaData',alpha);
                end
                
                % Set aspect ratio
                daspect(app.RawImage,[aratio 1 1]);
                
            end
             
            hold(app.RawImage,'off');
            drawnow;
            
        end % PlotImageFig


        function PlotMapsFig(app)
            
            cla(app.T1Image);
            cla(app.M0Image);
            hold(app.T1Image,'on');
            hold(app.M0Image,'on');
            
            if app.valid_fit
                
                % ----- T1 map -----
                
                % Select the data for the T1 map
                im = app.imageT1;
                
                % Select which slice to show
                im = permute(im,app.mask_orient(app.FitOrientationSpinner.Value,:));
                im = squeeze(im(app.FitSliceEditField.Value,:,:));
                
                % Label the orientations
                app.FitSliceLabel.Text = app.image_orient_label(app.FitOrientationSpinner.Value,1);
                app.FitXLabel1.Text = app.image_orient_label(app.FitOrientationSpinner.Value,2);
                app.FitYLabel1.Text = app.image_orient_label(app.FitOrientationSpinner.Value,3);
                app.FitXLabel2.Text = app.image_orient_label(app.FitOrientationSpinner.Value,2);
                app.FitYLabel2.Text = app.image_orient_label(app.FitOrientationSpinner.Value,3);
                
                % Image aspect ratio
                aratio = CalcAspectratioFnc(app,app.FitXLabel1.Text,app.FitYLabel1.Text,size(im,1),size(im,2));
                
                % Plot the image
                app.T1Image.Position = [11,417,300,300];
                xlim(app.T1Image,[0, size(im,1) + 1]);
                ylim(app.T1Image,[0, size(im,2) + 1]);
                
                % Plot the T1 map
                imshow(rot90(im),[0 app.MaxT1EditField.Value],'Colormap',app.t1cmap,'InitialMagnification','fit','Parent',app.T1Image);
                colorbar(app.T1Image,'east','Ticks',[]);
                
                % Set aspect ratio
                daspect(app.T1Image,[aratio 1 1]);
                
                
                % ----- M0 map -----
                
                % Select the data for the M0 map
                im = app.imageM0;
                
                % Estimate a suitable scale
                scale = 2*median(nonzeros(app.imageM0(:)))+1;
                if isnan(scale) scale = 32767; end
                if scale==0 scale = 32767; end
                
                % Select which slice to show
                im = permute(im,app.mask_orient(app.FitOrientationSpinner.Value,:));
                im = squeeze(im(app.FitSliceEditField.Value,:,:));
                
                % Plot the image
                app.M0Image.Position = [322,417,300,300];
                xlim(app.M0Image,[0, size(im,1) + 1]);
                ylim(app.M0Image,[0, size(im,2) + 1]);
                
                % Plot the T1 map
                imshow(rot90(im),[0 scale],'Colormap',gray,'InitialMagnification','fit','Parent',app.M0Image);
                
                % Set aspect ratio
                daspect(app.M0Image,[aratio 1 1]);
                
            end
            
            hold(app.T1Image,'off');
            hold(app.M0Image,'off');
            drawnow;
            
        end


        function PlotFits(app,fa,data,tr,t1,m0)
         
            % ----- Signal versus flip-angle curve -----
            
            cla(app.T1FitFig);
            hold(app.T1FitFig,'on');
            
            if m0 > 0 
                
                % data
                app.T1FitFig.Position = [13,4,285,220];
                xlim(app.T1FitFig,[0,max(fa(:))+5]);
                ylim(app.T1FitFig,[0,1.4*max(data(:))]);
                scatter(app.T1FitFig,fa,data);
                
                % fit
                a = 0 : 0.25: max(fa(:))+5;
                s = m0*sin(pi*a./180).*(1-exp(-tr/t1))./(1-cos(pi.*a/180)*exp(-tr/t1));
                plot(app.T1FitFig,a,s);
                
                % label
                app.FittedT1Label.Text = ['T1 = ',num2str(round(t1)),' ms'];
                
            else
                
                % Show no data
                cla(app.T1FitFig);
                
                % label
                app.FittedT1Label.Text = '';
                
            end
            
            hold(app.T1FitFig,'off');
            
        
            % ----- DESPOT1 representation -----
            
            cla(app.DespotT1FitFig);
            hold(app.DespotT1FitFig,'on');
            
            if m0 > 0 
                
                % data
                x = data./tan(pi*fa/180);
                y = data./sin(pi*fa/180);
                app.DespotT1FitFig.Position = [322,4,285,220];
                scatter(app.DespotT1FitFig,x,y);
                xlim(app.DespotT1FitFig,[0.1*min(x),1.2*max(x)]);
              
                % fit
                a = 0 : 0.25: max(fa(:))+5;
                s = m0*sin(pi*a./180).*(1-exp(-tr/t1))./(1-cos(pi.*a/180)*exp(-tr/t1));
                da = s./tan(pi*a/180);
                ds = s./sin(pi*a/180);
                plot(app.DespotT1FitFig,da,ds);
                
            else
                
                cla(app.DespotT1FitFig);
                
            end
            
            hold(app.DespotT1FitFig,'off');
            
        end % PlotFits


    end


    % ----------------------------------------------------------------------------


    methods (Access = public)
        
        
        function TextMessage(app, message)
            
            % Text messages in the app
            app.MessageBox.Value = [message ; app.MessageBox.Value];
            drawnow;
            
        end % TextMessage
        
        
        function ShowRegistrationProgress(app, progress)
            
            % Update the progress bar
            app.RegistrationProgressGauge.Value = progress;
            
        end % ShowRegistrationProgress
        
        
        function ShowFitProgress(app, progress)
            
            % Update the progress bar
            app.FitProgressGauge.Value = progress;
            
        end % ShowFitProgress
        
        
    end


    % ----------------------------------------------------------------------------


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % empty file import and export paths
            app.data_import_path = '';
            app.dicom_export_path = '';
            app.nifti_export_path = '';
            app.gif_export_path = '';
            
            % start the parallel pool if it is not running already
            p = gcp('nocreate');
            if isempty(p) 
                parpool; 
            end
          
            % add subfolders to search path
            if (~isdeployed)
                app.olddir = pwd;
                info = dir(which('T1mapping.mlapp'));
                app.appdir = info.folder;
                cd(app.appdir);
                addpath(genpath(pwd));
            end
            
            % add the bart path to the environment variable if available
            app.bart_detected = false;
            if isfile('bartpath.txt')
                app.bart_detected = true;
                app.TextMessage('BART toolbox detected ...');
                setenv('TOOLBOX_PATH', fileread('bartpath.txt'));
            end
            
            app.SignalFunctionImage.ImageSource = [app.appdir,filesep,'images',filesep,'S.gif'];
            app.TulpImage.ImageSource = [app.appdir,filesep,'images',filesep,'AmsterdamUMC.gif'];
            app.Image.ImageSource = [app.appdir,filesep,'images',filesep,'square.gif'];
            
            % suppress unneccesary warnings
            warning off;
            
            % some other settings
            app.valid_reco = false;
            app.valid_datafile = false;
            app.valid_fit = false;
            app.valid_mask = false;
            
        end

        % Value changed function: MRSolutionsCheckBox
        function MRSolutionsCheckBoxValueChanged(app, event)
            
            % set data format to MR Solutions
            app.BrukerCheckBox.Value = 0;
            app.MRSolutionsCheckBox.Value = 1;
            
        end

        % Value changed function: BrukerCheckBox
        function BrukerCheckBoxValueChanged(app, event)
            
            % set data format to Bruker
            app.BrukerCheckBox.Value = 1;
            app.MRSolutionsCheckBox.Value = 0;
            
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            
            
            % Indicate busy
            SetLights(app,{'Yellow','Red','Red','Red','Red','Red','Red'});
            SetButtons(app,{'off','off','off','off','off','off','off','off'});
            
            app.valid_datafile = false;
            app.valid_fit = false;
            
            if app.BrukerCheckBox.Value == 1
                
                % --------------------------
                % --- Bruker data import ---
                % --------------------------
                
                % Select multiple FID files
                app.T1mappingUIFigure.Visible = 'off';
                data_dirs = uipickfiles('FilterSpec',app.data_import_path,'Prompt','Select at least 3 data directories');
                app.T1mappingUIFigure.Visible = 'on';
                
                if length(data_dirs)>2
                    
                    app.valid_datafile = true;
                    
                    % Read the data and parameters from the Bruker directories
                    for i = 1:length(data_dirs)
                        
                        app.TextMessage(strcat('Reading Bruker data file',{' '},num2str(i),{' '},'...'));
                        
                        [app.data_import_path,fname] = fileparts(data_dirs{i});
                        data = PV3read(app.data_import_path,fname);
                        
                        dataslope = data{1,1}.Scan{1,1}.Reconstruction{1,1}.reco.reco.map_slope;                        % reco-slope
                        dataoffset = data{1,1}.Scan{1,1}.Reconstruction{1,1}.reco.reco.map_offset;                      % reco-offset
                        images(i,:,:,:) = (data{1,1}.Scan{1,1}.Reconstruction{1,1}.images/dataslope) + dataoffset;      % images:  data/slope + offset
                        alpha(i) = str2num(data{1,1}.Scan{1,1}.acqp.acq.flip_angle);                                    % flip angle
                        
                    end
                    
                    % Sequence parameters
                    app.seqpar.FOV3D = data{1,1}.Scan{1,1}.acqp.acq.fov*10;
                    app.seqpar.filename = 'Proton';
                    app.seqpar.tr = data{1,1}.Scan{1,1}.acqp.acq.repetition_time;
                    app.seqpar.te = data{1,1}.Scan{1,1}.acqp.acq.echo_time;
                    app.seqpar.NO_ECHOES = 1;
                    app.seqpar.NO_AVERAGES = str2num(data{1,1}.Scan{1,1}.acqp.NA);
                    app.seqpar.NO_REPETITIONS = str2num(data{1,1}.Scan{1,1}.acqp.NR);
                    app.seqpar.date = datetime;
                    app.seqpar.nucleus = '1H';
                    app.seqpar.PPL = data{1,1}.Scan{1,1}.acqp.acq.method;
                    app.seqpar.field_strength = str2num(data{1,1}.Scan{1,1}.acqp.BF1)/42.58;
                    app.seqpar.filename = 'data11111.dat';
                    app.seqpar.tag = 'Bruker111';
                    
                    % sort the images in ascending flip angle order
                    app.TextMessage('Sorting the data in ascending flip-angle order ...');
                    app.image4D = zeros(size(images));
                    [app.flipangles, index] = sort(alpha);
                    for i=1:length(index)
                        app.image4D(i,:,:,:) = images(index(i),:,:,:);
                    end
                    
                    % zerofill to nearest power of 2
                    if app.bart_detected
                        app.TextMessage('Zerofilling data to next power of 2 ...');
                        app.image4D = zerofill4D(app.image4D);
                    end
                   
                    app.image4D = round(32767*app.image4D/max(app.image4D(:)));
                    app.seqpar.matrix = [size(app.image4D,2),size(app.image4D,3),size(app.image4D,4)];
                    
                    app.seqpar.NO_SLICES = app.seqpar.matrix(1);
                    app.seqpar.SLICE_THICKNESS = app.seqpar.FOV3D(1)/app.seqpar.matrix(1);
                    app.seqpar.SLICE_INTERLEAVE = 1;
                    app.seqpar.SLICE_SEPARATION = app.seqpar.SLICE_THICKNESS;
                    app.seqpar.SMX = app.seqpar.FOV3D(2)/app.seqpar.matrix(2);
                    app.seqpar.SMY = app.seqpar.FOV3D(3)/app.seqpar.matrix(3);
                    app.seqpar.FOV = app.seqpar.FOV3D(3);
                    app.seqpar.NO_VIEWS = app.seqpar.matrix(2);
                    app.seqpar.alpha = alpha(1);
                    
                    app.valid_reco = true;
                    app.TextMessage('Data import ready ...');
                    
                end
                
            else
                
                % --------------------------------
                % --- MR Solutions data import ---
                % --------------------------------
                
                % Select 1 MRD file which contains multiple flip-angles data
                app.T1mappingUIFigure.Visible = 'off';
                [mrdfile,app.data_import_path] = uigetfile(strcat(app.mrd_import_path,filesep,'*.MRD'));
                app.T1mappingUIFigure.Visible = 'on';
                
                validfile = false;
                
                if ischar(mrdfile)
                    % MRD file selected
                    mrdfile = [app.data_import_path,mrdfile];
                    
                    % Load the data
                    app.TextMessage('Reading MRD file ...');
                    [data,~,app.seqpar] = Get_mrd_3D4(mrdfile,'seq','cen');
                    
                    % valid file
                    if app.seqpar.VFA_size > 2
                        app.valid_datafile = true;
                       
                        % 3D fft the data
                        app.TextMessage('Fast Fourier transform ...');
                        for i=1:app.seqpar.VFA_size
                            images(i,:,:,:) = abs(fft3reco(squeeze(data(i,:,:,:))));
                        end
                        
                        % sort according to flip angles
                        app.TextMessage('Sorting the data in ascending flip-angle order ...');
                        app.image4D = zeros(size(images));
                        [app.flipangles, index] = sort(app.seqpar.VFA_angles);
                        app.flipangles = app.flipangles(1:size(images,1));
                        for i=1:size(images,1)
                            app.image4D(i,:,:,:) = images(index(i),:,:,:);
                        end
                        
                        % zerofill to nearest power of 2
                        if app.bart_detected
                            app.TextMessage('Zerofilling data to next power of 2 ...');
                            app.image4D = zerofill4D(app.image4D);
                        end
                        
                        % data = (flipangles, readout x, dimy, dimz)
                        app.image4D = permute(app.image4D,[1,4,3,2]);
                        app.image4D = flip(app.image4D,2);
                        app.image4D = flip(app.image4D,3);
                        
                        % Set dimension parameters
                        app.image4D = round(32767*app.image4D/max(app.image4D(:)));
                        app.seqpar.matrix = [size(app.image4D,2),size(app.image4D,3),size(app.image4D,4)];
                        aratio = app.seqpar.SMY/app.seqpar.SMX;
                        app.seqpar.FOV3D = [app.seqpar.FOV,aratio*app.seqpar.FOV,app.seqpar.SLICE_THICKNESS];
                        
                        % Set some other parameters
                        app.seqpar.NO_SLICES = app.seqpar.matrix(1);
                        app.seqpar.SLICE_THICKNESS = app.seqpar.FOV3D(1)/app.seqpar.matrix(1);
                        app.seqpar.SLICE_INTERLEAVE = 1;
                        app.seqpar.SLICE_SEPARATION = app.seqpar.SLICE_THICKNESS;
                        app.seqpar.SMX = app.seqpar.FOV3D(2)/app.seqpar.matrix(2);
                        app.seqpar.SMY = app.seqpar.FOV3D(3)/app.seqpar.matrix(3);
                        app.seqpar.FOV = app.seqpar.FOV3D(3);
                        app.seqpar.NO_VIEWS = app.seqpar.matrix(2);
                        app.seqpar.alpha = app.flipangles(1);
                        [~,app.seqpar.tag,~] = fileparts(mrdfile);
                        if ismac
                            app.seqpar.PPL = replace(app.seqpar.PPL,'\','/');
                        end
                        [~,name,ext] = fileparts(app.seqpar.PPL);
                        app.seqpar.PPL = strcat(name,ext);
                        
                        app.valid_reco = true;
                        app.TextMessage('Data import ready ...');
                        
                    end
                    
                end
                
            end
            
            % Report on valid/invalid file selection
            if app.valid_datafile
                
                % Set the buttons for valid file
                SetLights(app,{'Green','Red','Red','Red','Red','Red','Red'});
                SetButtons(app,{'on','on','on','on','off','off','off','on'});
                
                % Set other parameters
                SetParametersFcn(app);
                OrientationSpinnerValueChanged(app, event);
                PlotImageFig(app);
                
            else
                
                % Not a valid data file
                uialert(app.T1mappingUIFigure,'Please select 3D data with at least 3 flip angles','Error');
                app.data_import_path = '';
                
                % Set the buttons for invalid data file
                SetLights(app,{'Red','Red','Red','Red','Red','Red','Red'});
                SetButtons(app,{'on','off','off','off','off','off','off','on'});
                
            end
            
        end

        % Value changed function: SliceEditField
        function SliceEditFieldValueChanged(app, event)
            
            if app.valid_reco
                
                app.SliceSlider.Value = app.SliceEditField.Value;
                PlotImageFig(app);
                
            end
            
        end

        % Value changed function: SliceSlider
        function SliceSliderValueChanged(app, event)
            
            if app.valid_reco
                
                app.SliceEditField.Value = round(app.SliceSlider.Value);
                PlotImageFig(app);
                
            end
            
        end

        % Value changed function: ImageEditField
        function ImageEditFieldValueChanged(app, event)
             
            if app.valid_reco
                
                app.ImageSlider.Value = app.ImageEditField.Value;
                PlotImageFig(app);
                
            end
            
        end

        % Value changed function: ImageSlider
        function ImageSliderValueChanged(app, event)
            
            if app.valid_reco
                
                app.ImageEditField.Value = round(app.ImageSlider.Value);
                PlotImageFig(app);
                
            end
            
        end

        % Value changed function: OrientationSpinner
        function OrientationSpinnerValueChanged(app, event)
            
            if app.valid_reco
                
                % reset the limits to the appropriate bounds
                app.SliceEditField.Value = 1;
                app.SliceSlider.Value = 1;
                
                app.SliceEditField.Limits = [1 size(app.image4D,app.image_orient(app.OrientationSpinner.Value,2))];
                app.SliceSlider.Limits = [1 size(app.image4D,app.image_orient(app.OrientationSpinner.Value,2))];
                
                app.SliceEditField.Value = round(size(app.image4D,app.image_orient(app.OrientationSpinner.Value,2))/2);
                app.SliceSlider.Value = round(size(app.image4D,app.image_orient(app.OrientationSpinner.Value,2))/2);
               
                % plot the image
                PlotImageFig(app);
                
             end
            
        end

        % Value changed function: IntensitySlider
        function IntensitySliderValueChanged(app, event)
            
            if app.valid_reco
                
                PlotImageFig(app);
                
            end
        
        end

        % Button pushed function: RegisterButton
        function RegisterButtonPushed(app, event)
            
            if app.valid_reco
                
                app.TextMessage('Automatic image registration ...');
                
                % Indicate busy
                SetLights(app,{'Null','Yellow','Red','Red','Red','Red','Red'});
                SetButtons(app,{'off','off','off','off','off','off','off','off'});
                
                % Initialize progress gauge
                app.RegistrationProgressGauge.Value = 0;
                
                % Register all slices to 1st echo time image
                app.image4D = register_images_3DT1(app,app.image4D,app.maxidx);
                
                app.TextMessage('Automatic image registration is ready ...');
                
                % Reset buttons
                SetLights(app,{'Null','Green','Red','Red','Red','Red','Red'});
                SetButtons(app,{'on','on','on','on','off','off','off','on'});
                
            end
            
        end

        % Button pushed function: SegmentButton
        function SegmentButtonPushed(app, event)
          
            if app.valid_reco
                
                app.TextMessage('Automatic image segmentation ...');
                
                % Indicate busy
                SetLights(app,{'Null','Null','Yellow','Red','Red','Red','Red'});
                SetButtons(app,{'off','off','off','off','off','off','off','off'});
                
                % Determine automatic threshold using the Otsu method
                app.threshold = double(graythresh(mat2gray(squeeze(app.image4D(app.maxidx,:,:,:)))) * max(squeeze(app.image4D(app.maxidx,:))));
                
                % Set the automatic mask for all slices
                app.mask = apply_threshold_3D(squeeze(app.image4D(app.maxidx,:,:,:)),app.threshold);
                
                % Display the threshold value for the current slice
                app.ThresholdEditField.Value = round(app.threshold);
                app.ThresholdSlider.Value = round(app.threshold);
                
                % Mask is valid
                app.valid_mask = true;
                
                % Display the mask in the image
                PlotImageFig(app);
                
                % Reset buttons
                SetLights(app,{'Null','Null','Green','Red','Red','Red','Red'});
                SetButtons(app,{'on','on','on','on','off','off','off','on'});
                
            end
            
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            
            app.ThresholdSlider.Value = app.ThresholdEditField.Value;
            
            if app.valid_reco
                
                % Set the automatic mask for all slices
                app.mask = apply_threshold_3D(squeeze(app.image4D(app.maxidx,:,:,:)),app.ThresholdEditField.Value);
                
                % Mask is valid
                app.valid_mask = true;
                
                % Plot the images with the new mask
                PlotImageFig(app);
                
                % Reset buttons
                SetLights(app,{'Null','Null','Green','Red','Red','Red','Red'});
                SetButtons(app,{'on','on','on','on','off','off','off','on'});
                
            end
            
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            
            app.ThresholdEditField.Value = round(app.ThresholdSlider.Value);
            ThresholdEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: ErodeButton
        function ErodeButtonPushed(app, event)
            
            if app.valid_reco
                
                % Morphological erosion 
                app.mask = 1-imerode(1-app.mask,[0 1 0; 1 1 1; 0 1 0]);
                
                % Display the result;
                PlotImageFig(app);
                
                % Reset buttons
                SetLights(app,{'Null','Null','Green','Red','Red','Red','Red'});
                SetButtons(app,{'on','on','on','on','off','off','off','on'});
                
            end
        
        end

        % Button pushed function: DilateButton
        function DilateButtonPushed(app, event)
            
            if app.valid_reco
                
                % Morphological dilation 
                app.mask = 1-imdilate(1-app.mask,[0 1 0; 1 1 1; 0 1 0]);
                
                % Display the result;
                PlotImageFig(app);
                
                % Reset buttons
                SetLights(app,{'Null','Null','Green','Red','Red','Red','Red'});
                SetButtons(app,{'on','on','on','on','off','off','off','on'});
                
            end
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            
            if app.valid_reco
                
                % Morphological open 
                app.mask = 1-imopen(1-app.mask,[0 1 0; 1 1 1; 0 1 0]);
                
                % Display the result;
                PlotImageFig(app);
                
                % Reset buttons
                SetLights(app,{'Null','Null','Green','Red','Red','Red','Red'});
                SetButtons(app,{'on','on','on','on','off','off','off','on'});
                
            end
        
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
        
            if app.valid_reco
                
                % Morphological close 
                app.mask = 1-imclose(1-app.mask,[0 1 0; 1 1 1; 0 1 0]);
                
                % Display the result;
                PlotImageFig(app);
                
                % Reset buttons
                SetLights(app,{'Null','Null','Green','Red','Red','Red','Red'});
                SetButtons(app,{'on','on','on','on','off','off','off','on'});
                
            end    
        
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
          
            if app.valid_reco
                
                % Disable segmentation buttons to prevent change during fit
                DisableSegmentButtons(app);
                SetLights(app,{'Null','Null','Null','Yellow','Red','Red','Red'});
                SetButtons(app,{'off','off','off','off','off','off','off','off'});
               
                % Progress gauge parameters
                totalnumberofsteps = app.FitRangeEndEditField.Value - app.FitRangeStartEditField.Value + 1;
                app.FitProgressGauge.Value = 0;
                app.abort_fit = false;
                
                app.TextMessage('Fitting ...');
                app.TextMessage('WARNING: this may take a while ~ 5 sec / slice ...');
                
                tic;
                
                % Loop along all x dimension
                for i = app.FitRangeStartEditField.Value:app.FitRangeEndEditField.Value  % x-dim
                    
                    % Fit function
                    [app.imageT1(i,:,:),app.imageM0(i,:,:)] = dotheT1fit_yzdim_despot(squeeze(app.image4D(:,i,:,:)), squeeze(app.mask(i,:,:)), app.flipangles, app.seqpar.tr);
                    
                    % Update the fit progress gauge
                    app.FitProgressGauge.Value = round(100*((i-app.FitRangeStartEditField.Value+1)/totalnumberofsteps));
                    
                    % Abort fit if abort button pressed
                    if app.abort_fit 
                        
                        app.TextMessage('Fitting aborted ...');
                        
                        % Reset buttons
                        SetLights(app,{'Null','Null','Null','Red','Red','Red','Red'});
                        SetButtons(app,{'on','on','on','on','off','off','off','on'});
                        drawnow;
                        
                        break; 
                    end
                    
                    drawnow;
                    
                end
                
                if ~app.abort_fit 
                    
                    app.TextMessage('Fitting ready ...'); 
                    elapsedtime = toc;
                    app.TextMessage(strcat('Fitted',{' '},num2str(app.FitRangeEndEditField.Value-app.FitRangeStartEditField.Value+1),{' '},'slices in',{' '},num2str(elapsedtime),{' '},'s ...'));
                    app.valid_fit = true;
                    FitOrientationSpinnerValueChanged(app, event);
                    AutoButtonPushed(app, event);
                    
                    % Reset buttons
                    SetLights(app,{'Null','Null','Null','Green','Red','Red','Red'});
                    SetButtons(app,{'on','on','on','on','on','on','on','on'});
                    
                end
                
                % Re-enable the segmentation buttons
                EnableSegmentButtons(app);
                
            end
            
        end

        % Value changed function: FitRangeEndEditField, 
        % FitRangeStartEditField
        function FitRangeStartEditFieldValueChanged(app, event)
            
            if app.FitRangeStartEditField.Value > app.FitRangeEndEditField.Value
                
                app.FitRangeStartEditField.Value = app.FitRangeEndEditField.Value;
                
            end
            
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            
            app.abort_fit = true;
        
        end

        % Selection changed function: ColormapButtonGroup
        function ColormapButtonGroupSelectionChanged(app, event)
            
            if app.grayButton.Value == 1
                app.t1cmap = gray;
            end
            
            if app.autumnButton.Value == 1
                app.t1cmap = autumn;
            end
            
            if app.winterButton.Value == 1
                app.t1cmap = winter;
            end
            
            if app.parulaButton.Value == 1
                app.t1cmap = parula;
            end
            
            if app.hotButton.Value == 1
                app.t1cmap = hot;
            end
            
            % Background should always be black
            app.t1cmap(1,:) = [0 0 0];
            
            % Plot the maps with the new color map
            PlotMapsFig(app);
            
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
            
            if app.valid_fit
                
                % Estimate a suitable scale for the T1 map
                % Auto-scale = 2 x mean of the T1 values in the image
                scale = round(2*mean(nonzeros(app.imageT1(:))));
                
                if isnan(scale) scale = 1000; end
                if scale==0 scale = 1000; end
                if scale>5000 scale = 5000; end
                app.MaxT1EditField.Value = scale;
                
                % plot the maps
                PlotMapsFig(app);
                
            end
            
        end

        % Value changed function: MaxT1EditField
        function MaxT1EditFieldValueChanged(app, event)
            
            % plot the maps
            PlotMapsFig(app);
            
        end

        % Value changed function: FitSliceEditField
        function FitSliceEditFieldValueChanged(app, event)
             
            if app.valid_fit
                
                app.FitSliceSlider.Value = app.FitSliceEditField.Value;
                PlotMapsFig(app);
                
            end
            
        end

        % Value changed function: FitSliceSlider
        function FitSliceSliderValueChanged(app, event)
            
            if app.valid_fit
                
                app.FitSliceEditField.Value = round(app.FitSliceSlider.Value);
                PlotMapsFig(app);
                
            end
             
        end

        % Value changed function: FitOrientationSpinner
        function FitOrientationSpinnerValueChanged(app, event)
            
            if app.valid_fit
                
                % reset the limits to the appropriate bounds
                app.FitSliceEditField.Value = 1;
                app.FitSliceSlider.Value = 1;
                
                app.FitSliceEditField.Limits = [1 size(app.imageT1,app.mask_orient(app.FitOrientationSpinner.Value,1))];
                app.FitSliceSlider.Limits = [1 size(app.imageT1,app.mask_orient(app.FitOrientationSpinner.Value,1))];
                
                app.FitSliceEditField.Value = round(size(app.imageT1,app.mask_orient(app.FitOrientationSpinner.Value,1))/2);
                app.FitSliceSlider.Value = round(size(app.imageT1,app.mask_orient(app.FitOrientationSpinner.Value,1))/2);
               
                % plot the image
                PlotMapsFig(app);
                
             end
            
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
        
            if app.valid_fit
                
                % Get the Position of the GUI and the position of the mouse click
                set(0,'units','pixels');
                guiposition = get(app.T1mappingUIFigure,'Position');
                cursorposition = get(0,'PointerLocation');
                
                % Position of the cursor click in the image window
                imagepos = [730,462]; % position of bottom-left corner of image in the app ~ (730,463)
                Xpos = cursorposition(1)-guiposition(1)-imagepos(1);
                Ypos = cursorposition(2)-guiposition(2)-imagepos(2);
                
                % Image aspect ratio
                aratio = app.seqpar.FOV3D(app.mask_orient(app.FitOrientationSpinner.Value,2))/app.seqpar.FOV3D(app.mask_orient(app.FitOrientationSpinner.Value,3));
                
                % Image matrix size
                xsize = size(app.imageT1,app.mask_orient(app.FitOrientationSpinner.Value,2));
                ysize = size(app.imageT1,app.mask_orient(app.FitOrientationSpinner.Value,3));
                
                % Correct for non-square image
                imsize = 284;
                if aratio >= 1
                    xcor = (Xpos/imsize) * xsize;
                    ycor = (Ypos/imsize) * ysize * aratio - (0.5 * (aratio-1) * ysize);
                else
                    xcor = (Xpos/imsize) * xsize * (1/aratio) - (0.5 * ((1/aratio)-1) * xsize);     % untested
                    ycor = (Ypos/imsize) * ysize;
                end
                
                % Round to nearest integer
                xcor = round(xcor);
                ycor = round(ycor);
                
                % Keep the values within matrix boundaries
                if xcor < 1 xcor = 1; end
                if ycor < 1 ycor = 1; end
                if xcor > xsize xcor = xsize; end
                if ycor > ysize ycor = ysize; end
                
                % select the raw image data in the selected pixel
                im = app.image4D;
                im = permute(im,app.image_orient(app.FitOrientationSpinner.Value,:));
                im = squeeze(im(:,app.FitSliceEditField.Value,:,:));
                rawdata = im(:,xcor,ycor)';
                
                % select the T1 value in the selected pixel
                im = app.imageT1;
                im = permute(im,app.mask_orient(app.FitOrientationSpinner.Value,:));
                im = squeeze(im(app.FitSliceEditField.Value,:,:));
                t1value = im(xcor,ycor);
                
                % select the M0 value in the selected pixel
                im = app.imageM0;
                im = permute(im,app.mask_orient(app.FitOrientationSpinner.Value,:));
                im = squeeze(im(app.FitSliceEditField.Value,:,:));
                m0value = im(xcor,ycor);
                
                % Plot the raw data and fitted curves
                PlotFits(app,app.flipangles,rawdata,app.seqpar.tr,t1value,m0value);
                
            end
            
        end

        % Button pushed function: ExportDicomButton
        function ExportDicomButtonPushed(app, event)
            
            if app.valid_fit
                
                % Indicate busy
                SetLights(app,{'Null','Null','Null','Null','Yellow','Null','Null'});
                SetButtons(app,{'off','off','off','off','off','off','off','off'});
                
                % Ask for directory
                app.T1mappingUIFigure.Visible = 'off';
                app.dicom_export_path = uigetdir(app.dicom_export_path,'Dicom export path');
                app.T1mappingUIFigure.Visible = 'on';
                drawnow;
                
                % Check if directory exists
                ready = true;
                if ~ischar(app.dicom_export_path)   % user cancel
                    ready = false;
                    app.dicom_export_path = '';
                else
                    [~, Attrib] = fileattrib(app.dicom_export_path);
                    if ~any(Attrib.UserWrite)   % check if directory is writable
                        ready = false;
                    end
                end
                
                % Export to Dicom
                if ready
                    
                    app.TextMessage('Exporting to Dicom ...');
                    
                    % Dicom export
                    export_dicom_3DT1(app.dicom_export_path,app.imageM0,app.imageT1,app.seqpar,app.seqpar.tag);
                    
                    app.TextMessage('Dicom export finished ...');
                    
                    % Reset buttons
                    SetLights(app,{'Null','Null','Null','Null','Green','Null','Null'});
                    SetButtons(app,{'on','on','on','on','on','on','on','on'});
                    
                else
                    
                    f = app.T1mappingUIFigure;
                    uialert(f,'Not a valid directory','Error');
                    
                    % Reset buttons
                    SetLights(app,{'Null','Null','Null','Null','Red','Null','Null'});
                    SetButtons(app,{'on','on','on','on','on','on','on','on'});
                    
                end
                
            end
            
        end

        % Button pushed function: ExportNIFTIButton
        function ExportNIFTIButtonPushed(app, event)
            
            if app.valid_fit
                
                % Indicate busy
                SetLights(app,{'Null','Null','Null','Null','Null','Yellow','Null'});
                SetButtons(app,{'off','off','off','off','off','off','off','off'});
                
                % Ask for directory
                app.T1mappingUIFigure.Visible = 'off';
                app.nifti_export_path = uigetdir(app.nifti_export_path,'NIFTI export path');
                app.T1mappingUIFigure.Visible = 'on';
                drawnow;
                
                % Check if directory exists
                ready = true;
                if ~ischar(app.nifti_export_path)   % user cancel
                    ready = false;
                    app.nifti_export_path = '';
                else
                    [~, Attrib] = fileattrib(app.nifti_export_path);
                    if ~any(Attrib.UserWrite)   % check if directory is writable
                        ready = false;
                    end
                end
                
                % Export to NIFTI
                if ready
                    
                    app.TextMessage('Exporting to NIFTI ...');
                    
                    % Normalize images and determine dimensions
                    imT1 = single(app.imageT1);
                    imM0 = single(app.imageM0);
                    [dimx,dimy,dimz] = size(imT1);
                    
                    % Set some parameters
                    load('niftiinfo.mat');
                    info.Filemoddate = datestr(datetime);
                    info.Filesize = dimx*dimy*dimz*32;
                    info.ImageSize = [dimx dimy dimz];
                    info.PixelDimensions = [app.seqpar.FOV3D(1)/dimx app.seqpar.FOV3D(2)/dimy app.seqpar.FOV3D(3)/dimz];
                    info.raw.dim = [3 dimx dimy dimz 1 1 1 1];
                    info.raw.pixdim = [1 app.seqpar.FOV3D(1)/dimx app.seqpar.FOV3D(2)/dimy app.seqpar.FOV3D(3)/dimz 0 0 0 0];
                    info.Version = 1;
                    
                    % Export NIFTI
                    niftiwrite(imT1,[app.nifti_export_path,filesep,'im3D_T1.nii'],info);
                    niftiwrite(imM0,[app.nifti_export_path,filesep,'im3D_M0.nii'],info);
                    
                    app.TextMessage('NIFTI export finished ...');
                    
                    % Reset buttons
                    SetLights(app,{'Null','Null','Null','Null','Null','Green','Null'});
                    SetButtons(app,{'on','on','on','on','on','on','on','on'});
                    
                else
                    
                    f = app.T1mappingUIFigure;
                    uialert(f,'Not a valid directory','Error');
                    
                    % Reset buttons
                    SetLights(app,{'Null','Null','Null','Null','Null','Red','Null'});
                    SetButtons(app,{'on','on','on','on','on','on','on','on'});
                    
                end
                
            end
           
        end

        % Button pushed function: ExportGIFButton
        function ExportGIFButtonPushed(app, event)
            
            if app.valid_fit
                
                % Indicate busy
                SetLights(app,{'Null','Null','Null','Null','Null','Null','Yellow'});
                SetButtons(app,{'off','off','off','off','off','off','off','off'});
                
                % Ask for directory
                app.T1mappingUIFigure.Visible = 'off';
                app.gif_export_path = uigetdir(app.gif_export_path,'GIF export path');
                app.T1mappingUIFigure.Visible = 'on';
                drawnow;
                
                % Check if directory exists
                ready = true;
                if ~ischar(app.gif_export_path)   % user cancel
                    ready = false;
                    app.gif_export_path = '';
                else
                    [~, Attrib] = fileattrib(app.gif_export_path);
                    if ~any(Attrib.UserWrite)   % check if directory is writable
                        ready = false;
                    end
                end
                
                % Export to animated gif
                if ready
                    
                    % T1 map in viewing orientation
                    imT1 = app.imageT1;
                    imT1 = permute(imT1,app.mask_orient(app.FitOrientationSpinner.Value,:));
                    
                    % M0 map in viewing orientation
                    imM0 = app.imageM0;
                    imM0 = permute(imM0,app.mask_orient(app.FitOrientationSpinner.Value,:));
                    
                    % Image aspect ratio
                    aratio = CalcAspectratioFnc(app,app.FitXLabel1.Text,app.FitYLabel1.Text,size(imT1,2),size(imT1,3));
                    
                    app.TextMessage('Exporting to GIF ...');
                    
                    export_gif_3DT1(app.gif_export_path,imT1,imM0,app.seqpar.tag,app.MaxT1EditField.Value,app.t1cmap,aratio);
                    
                    app.TextMessage('Ready ...');
                    
                    % Reset buttons
                    SetLights(app,{'Null','Null','Null','Null','Null','Null','Green'});
                    SetButtons(app,{'on','on','on','on','on','on','on','on'});
                    
                else
                    
                    f = app.T1mappingUIFigure;
                    uialert(f,'Not a valid directory','Error');
                    
                    % Reset buttons
                    SetLights(app,{'Null','Null','Null','Null','Null','Null','Red'});
                    SetButtons(app,{'on','on','on','on','on','on','on','on'});
                    
                end
                
            end
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            
            % Remove subfolders from search path
            if (~isdeployed)
                info = dir(which('T1mapping.mlapp'));
                app.appdir = info.folder;
                rmpath(genpath(app.appdir));
                cd(app.olddir);
            end
            
            % Exit
            app.delete;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.T1mappingUIFigure = uifigure('Visible', 'off');
            app.DashboardPanel = uipanel(app.T1mappingUIFigure);
            app.DashboardPanel.Title = 'Dashboard';
            app.LoadDataButton = uibutton(app.DashboardPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load data';
            app.MRSolutionsCheckBox = uicheckbox(app.DashboardPanel);
            app.MRSolutionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @MRSolutionsCheckBoxValueChanged, true);
            app.MRSolutionsCheckBox.Text = 'MR Solutions';
            app.MRSolutionsCheckBox.Value = true;
            app.BrukerCheckBox = uicheckbox(app.DashboardPanel);
            app.BrukerCheckBox.ValueChangedFcn = createCallbackFcn(app, @BrukerCheckBoxValueChanged, true);
            app.BrukerCheckBox.Text = 'Bruker';
            app.RegisterButton = uibutton(app.DashboardPanel, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterButtonPushed, true);
            app.RegisterButton.Text = 'Register';
            app.SegmentButton = uibutton(app.DashboardPanel, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentButtonPushed, true);
            app.SegmentButton.Text = 'Segment';
            app.RegistrationProgressGauge = uigauge(app.DashboardPanel, 'linear');
            app.RegistrationProgressGauge.MajorTicks = [0 25 50 75 100];
            app.FitButton = uibutton(app.DashboardPanel, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.ExportDicomButton = uibutton(app.DashboardPanel, 'push');
            app.ExportDicomButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDicomButtonPushed, true);
            app.ExportDicomButton.Text = 'Export Dicom';
            app.ExportNIFTIButton = uibutton(app.DashboardPanel, 'push');
            app.ExportNIFTIButton.ButtonPushedFcn = createCallbackFcn(app, @ExportNIFTIButtonPushed, true);
            app.ExportNIFTIButton.Text = 'Export NIFTI';
            app.ExportGIFButton = uibutton(app.DashboardPanel, 'push');
            app.ExportGIFButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGIFButtonPushed, true);
            app.ExportGIFButton.Text = 'Export GIF';
            app.ExitButton = uibutton(app.DashboardPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.RegistrationProgressLabel = uilabel(app.DashboardPanel);
            app.RegistrationProgressLabel.Text = 'progress';
            app.FitProgressGauge = uigauge(app.DashboardPanel, 'linear');
            app.FitProgressGauge.MajorTicks = [0 25 50 75 100];
            app.FitProgressLabel = uilabel(app.DashboardPanel);
            app.FitProgressLabel.Text = 'progress';
            app.AbortButton = uibutton(app.DashboardPanel, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Text = 'Abort';
            app.Lamp1 = uilamp(app.DashboardPanel);
            app.Lamp2 = uilamp(app.DashboardPanel);
            app.Lamp3 = uilamp(app.DashboardPanel);
            app.Lamp4 = uilamp(app.DashboardPanel);
            app.Lamp5 = uilamp(app.DashboardPanel);
            app.Lamp6 = uilamp(app.DashboardPanel);
            app.Lamp7 = uilamp(app.DashboardPanel);
            app.XRangeLabel = uilabel(app.DashboardPanel);
            app.XRangeLabel.Text = 'X Range';
            app.FitRangeStartEditField = uieditfield(app.DashboardPanel, 'numeric');
            app.FitRangeStartEditField.Limits = [1 Inf];
            app.FitRangeStartEditField.ValueDisplayFormat = '%.0f';
            app.FitRangeStartEditField.ValueChangedFcn = createCallbackFcn(app, @FitRangeStartEditFieldValueChanged, true);
            app.FitRangeStartEditField.Value = 1;
            app.FitRangeEndEditField = uieditfield(app.DashboardPanel, 'numeric');
            app.FitRangeEndEditField.Limits = [1 Inf];
            app.FitRangeEndEditField.ValueChangedFcn = createCallbackFcn(app, @FitRangeStartEditFieldValueChanged, true);
            app.FitRangeEndEditField.Value = 1;
            app.TitleLabel = uilabel(app.T1mappingUIFigure);
            app.TitleLabel.Text = '3D RF-spoiled gradient-echo T1 mapping';
            app.RawimagesPanel = uipanel(app.T1mappingUIFigure);
            app.RawimagesPanel.Title = 'Raw images';
            app.RawImage = uiaxes(app.RawimagesPanel);
            title(app.RawImage, '')
            xlabel(app.RawImage, '')
            ylabel(app.RawImage, '')
            app.RawImage.XTick = [];
            app.RawImage.YTick = [];
            app.ImageSelectorPanel = uipanel(app.RawimagesPanel);
            app.ImageSelectorPanel.Title = 'Image Selector';
            app.SliceEditField = uieditfield(app.ImageSelectorPanel, 'numeric');
            app.SliceEditField.Limits = [1 256];
            app.SliceEditField.ValueDisplayFormat = '%.0f';
            app.SliceEditField.ValueChangedFcn = createCallbackFcn(app, @SliceEditFieldValueChanged, true);
            app.SliceEditField.Value = 1;
            app.SliceSlider = uislider(app.ImageSelectorPanel);
            app.SliceSlider.Limits = [1 256];
            app.SliceSlider.MajorTicks = [];
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @SliceSliderValueChanged, true);
            app.SliceSlider.MinorTicks = [];
            app.SliceSlider.Value = 1;
            app.ImageEditFieldLabel = uilabel(app.ImageSelectorPanel);
            app.ImageEditFieldLabel.Text = 'Image';
            app.ImageEditField = uieditfield(app.ImageSelectorPanel, 'numeric');
            app.ImageEditField.Limits = [1 256];
            app.ImageEditField.ValueDisplayFormat = '%.0f';
            app.ImageEditField.ValueChangedFcn = createCallbackFcn(app, @ImageEditFieldValueChanged, true);
            app.ImageEditField.Value = 1;
            app.ImageSlider = uislider(app.ImageSelectorPanel);
            app.ImageSlider.Limits = [1 256];
            app.ImageSlider.MajorTicks = [];
            app.ImageSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSliderValueChanged, true);
            app.ImageSlider.MinorTicks = [];
            app.ImageSlider.Value = 1;
            app.OrientationSpinnerLabel = uilabel(app.ImageSelectorPanel);
            app.OrientationSpinnerLabel.Text = 'Orientation';
            app.OrientationSpinner = uispinner(app.ImageSelectorPanel);
            app.OrientationSpinner.Limits = [1 3];
            app.OrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @OrientationSpinnerValueChanged, true);
            app.OrientationSpinner.Value = 1;
            app.SliceLabel = uilabel(app.ImageSelectorPanel);
            app.SliceLabel.Text = 'Slice';
            app.SegmentationPanel = uipanel(app.RawimagesPanel);
            app.SegmentationPanel.Title = 'Segmentation';
            app.ThresholdEditFieldLabel = uilabel(app.SegmentationPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.SegmentationPanel, 'numeric');
            app.ThresholdEditField.Limits = [0 Inf];
            app.ThresholdEditField.ValueDisplayFormat = '%.0f';
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdSlider = uislider(app.SegmentationPanel);
            app.ThresholdSlider.Limits = [1 32676];
            app.ThresholdSlider.MajorTicks = [];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.MinorTicks = [];
            app.ThresholdSlider.Value = 1;
            app.ErodeButton = uibutton(app.SegmentationPanel, 'push');
            app.ErodeButton.ButtonPushedFcn = createCallbackFcn(app, @ErodeButtonPushed, true);
            app.ErodeButton.Text = 'Erode';
            app.DilateButton = uibutton(app.SegmentationPanel, 'push');
            app.DilateButton.ButtonPushedFcn = createCallbackFcn(app, @DilateButtonPushed, true);
            app.DilateButton.Text = 'Dilate';
            app.CloseButton = uibutton(app.SegmentationPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.SegmentationPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.IntensitySlider = uislider(app.RawimagesPanel);
            app.IntensitySlider.Limits = [0 2];
            app.IntensitySlider.MajorTicks = [];
            app.IntensitySlider.ValueChangedFcn = createCallbackFcn(app, @IntensitySliderValueChanged, true);
            app.IntensitySlider.MinorTicks = [];
            app.IntensitySlider.Value = 1;
            app.IntensityLabel = uilabel(app.RawimagesPanel);
            app.IntensityLabel.Text = 'Intensity';
            app.ImageLabel = uilabel(app.RawimagesPanel);
            app.ImageLabel.Text = 'Image';
            app.XLabel = uilabel(app.RawimagesPanel);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.RawimagesPanel);
            app.YLabel.Text = 'Y';
            app.CreditsLabel = uilabel(app.T1mappingUIFigure);
            app.CreditsLabel.Text = 'Gustav Strijkers, Amsterdam UMC, Version 1.0';
            app.TulpImage = uiimage(app.T1mappingUIFigure);
            app.MessagesPanel = uipanel(app.T1mappingUIFigure);
            app.MessagesPanel.Title = 'Messages';
            app.MessageBox = uitextarea(app.MessagesPanel);
            app.MessageBox.Editable = 'off';
            app.SequenceparametersPanel = uipanel(app.T1mappingUIFigure);
            app.SequenceparametersPanel.Title = 'Sequence parameters';
            app.TRmsLabel = uilabel(app.SequenceparametersPanel);
            app.TRmsLabel.Text = 'TR (ms)';
            app.TRViewField = uieditfield(app.SequenceparametersPanel, 'numeric');
            app.TRViewField.Editable = 'off';
            app.TEmsLabel = uilabel(app.SequenceparametersPanel);
            app.TEmsLabel.Text = 'TE (ms)';
            app.TEViewField = uieditfield(app.SequenceparametersPanel, 'numeric');
            app.TEViewField.Editable = 'off';
            app.SequenceEditFieldLabel = uilabel(app.SequenceparametersPanel);
            app.SequenceEditFieldLabel.Text = 'Sequence';
            app.SequenceViewField = uieditfield(app.SequenceparametersPanel, 'text');
            app.SequenceViewField.Editable = 'off';
            app.MatrixEditFieldLabel = uilabel(app.SequenceparametersPanel);
            app.MatrixEditFieldLabel.Text = 'Matrix';
            app.MatrixViewField1 = uieditfield(app.SequenceparametersPanel, 'numeric');
            app.MatrixViewField1.ValueDisplayFormat = '%.0f';
            app.MatrixViewField1.Editable = 'off';
            app.MatrixViewField2 = uieditfield(app.SequenceparametersPanel, 'numeric');
            app.MatrixViewField2.ValueDisplayFormat = '%.0f';
            app.MatrixViewField2.Editable = 'off';
            app.MatrixViewField3 = uieditfield(app.SequenceparametersPanel, 'numeric');
            app.MatrixViewField3.ValueDisplayFormat = '%.0f';
            app.MatrixViewField3.Editable = 'off';
            app.FOVViewField2 = uieditfield(app.SequenceparametersPanel, 'numeric');
            app.FOVViewField2.ValueDisplayFormat = '%.0f';
            app.FOVViewField2.Editable = 'off';
            app.FOVViewField3 = uieditfield(app.SequenceparametersPanel, 'numeric');
            app.FOVViewField3.ValueDisplayFormat = '%.0f';
            app.FOVViewField3.Editable = 'off';
            app.FOVmmLabel = uilabel(app.SequenceparametersPanel);
            app.FOVmmLabel.Text = 'FOV (mm)';
            app.FOVViewField1 = uieditfield(app.SequenceparametersPanel, 'numeric');
            app.FOVViewField1.ValueDisplayFormat = '%.0f';
            app.FOVViewField1.Editable = 'off';
            app.FlipanglesEditFieldLabel = uilabel(app.SequenceparametersPanel);
            app.FlipanglesEditFieldLabel.Text = 'Flip angles';
            app.FlipAnglesViewField = uieditfield(app.SequenceparametersPanel, 'text');
            app.FlipAnglesViewField.Editable = 'off';
            app.XLabel_2 = uilabel(app.SequenceparametersPanel);
            app.XLabel_2.Text = 'X';
            app.YLabel_2 = uilabel(app.SequenceparametersPanel);
            app.YLabel_2.Text = 'Y';
            app.ZLabel = uilabel(app.SequenceparametersPanel);
            app.ZLabel.Text = 'Z';
            app.FitresultsPanel = uipanel(app.T1mappingUIFigure);
            app.FitresultsPanel.Title = 'Fit results';
            app.M0Image = uiaxes(app.FitresultsPanel);
            title(app.M0Image, '')
            xlabel(app.M0Image, '')
            ylabel(app.M0Image, '')
            app.M0Image.XTick = [];
            app.M0Image.YTick = [];
            app.T1Label = uilabel(app.FitresultsPanel);
            app.T1Label.Text = 'T1';
            app.M0Label = uilabel(app.FitresultsPanel);
            app.M0Label.Text = 'M0';
            app.FitImageSelectorPanel = uipanel(app.FitresultsPanel);
            app.FitImageSelectorPanel.Title = 'Image Selector';
            app.FitSliceEditField = uieditfield(app.FitImageSelectorPanel, 'numeric');
            app.FitSliceEditField.Limits = [1 256];
            app.FitSliceEditField.ValueDisplayFormat = '%.0f';
            app.FitSliceEditField.ValueChangedFcn = createCallbackFcn(app, @FitSliceEditFieldValueChanged, true);
            app.FitSliceEditField.Value = 1;
            app.FitSliceSlider = uislider(app.FitImageSelectorPanel);
            app.FitSliceSlider.Limits = [1 256];
            app.FitSliceSlider.MajorTicks = [];
            app.FitSliceSlider.ValueChangedFcn = createCallbackFcn(app, @FitSliceSliderValueChanged, true);
            app.FitSliceSlider.MinorTicks = [];
            app.FitSliceSlider.Value = 1;
            app.OrientationSpinner_2Label_2 = uilabel(app.FitImageSelectorPanel);
            app.OrientationSpinner_2Label_2.Text = 'Orientation';
            app.FitOrientationSpinner = uispinner(app.FitImageSelectorPanel);
            app.FitOrientationSpinner.Limits = [1 3];
            app.FitOrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @FitOrientationSpinnerValueChanged, true);
            app.FitOrientationSpinner.Value = 1;
            app.FitSliceLabel = uilabel(app.FitImageSelectorPanel);
            app.FitSliceLabel.Text = 'Slice';
            app.ColormapButtonGroup = uibuttongroup(app.FitresultsPanel);
            app.ColormapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColormapButtonGroupSelectionChanged, true);
            app.ColormapButtonGroup.Title = 'Color map';
            app.grayButton = uiradiobutton(app.ColormapButtonGroup);
            app.grayButton.Text = 'gray';
            app.grayButton.Value = true;
            app.autumnButton = uiradiobutton(app.ColormapButtonGroup);
            app.autumnButton.Text = 'autumn';
            app.winterButton = uiradiobutton(app.ColormapButtonGroup);
            app.winterButton.Text = 'winter';
            app.parulaButton = uiradiobutton(app.ColormapButtonGroup);
            app.parulaButton.Text = 'parula';
            app.hotButton = uiradiobutton(app.ColormapButtonGroup);
            app.hotButton.Text = 'hot';
            app.MaxT1EditField = uieditfield(app.ColormapButtonGroup, 'numeric');
            app.MaxT1EditField.Limits = [1 5000];
            app.MaxT1EditField.ValueDisplayFormat = '%.0f';
            app.MaxT1EditField.ValueChangedFcn = createCallbackFcn(app, @MaxT1EditFieldValueChanged, true);
            app.MaxT1EditField.Value = 2000;
            app.MaxmsLabel = uilabel(app.ColormapButtonGroup);
            app.MaxmsLabel.Text = 'Max (ms)';
            app.AutoButton = uibutton(app.ColormapButtonGroup, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Text = 'Auto';
            app.T1FitFig = uiaxes(app.FitresultsPanel);
            title(app.T1FitFig, '')
            xlabel(app.T1FitFig, 'α')
            ylabel(app.T1FitFig, 'S')
            app.T1FitFig.XLim = [0 100];
            app.T1FitFig.XTick = [0 10 20 30 40 50 60 70 80 90 100];
            app.T1FitFig.YTick = [];
            app.DespotT1FitFig = uiaxes(app.FitresultsPanel);
            title(app.DespotT1FitFig, '')
            xlabel(app.DespotT1FitFig, 'S / tan(α)')
            ylabel(app.DespotT1FitFig, 'S / sin(α)')
            app.DespotT1FitFig.XLim = [0 100];
            app.DespotT1FitFig.XTick = [0 10 20 30 40 50 60 70 80 90 100];
            app.DespotT1FitFig.YTick = [];
            app.SignalFunctionImage = uiimage(app.FitresultsPanel);
            app.FitXLabel1 = uilabel(app.FitresultsPanel);
            app.FitXLabel1.Text = 'X';
            app.FitXLabel2 = uilabel(app.FitresultsPanel);
            app.FitXLabel2.Text = 'X';
            app.FitYLabel1 = uilabel(app.FitresultsPanel);
            app.FitYLabel1.Text = 'Y';
            app.FitYLabel2 = uilabel(app.FitresultsPanel);
            app.FitYLabel2.Text = 'Y';
            app.T1Image = uiaxes(app.FitresultsPanel);
            title(app.T1Image, '')
            xlabel(app.T1Image, '')
            ylabel(app.T1Image, '')
            app.T1Image.XTick = [];
            app.T1Image.YTick = [];
            app.Image = uiimage(app.FitresultsPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.SignalflipangleLabel = uilabel(app.FitresultsPanel);
            app.SignalflipangleLabel.Text = 'Signal - flip-angle';
            app.DESPOT1Label = uilabel(app.FitresultsPanel);
            app.DESPOT1Label.Text = 'DESPOT1';
            app.FittedT1Label = uilabel(app.FitresultsPanel);
            app.FittedT1Label.Text = 'T1';
            % Show the figure after all components are created
            app.T1mappingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = T1mapping

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.T1mappingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.T1mappingUIFigure)
        end
    end
end